classdef SMILE_SB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMILE23UIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImagesTab                       matlab.ui.container.Tab
        ExportcontactdetailsCheckBox    matlab.ui.control.CheckBox
        BatchmodeCheckBox               matlab.ui.control.CheckBox
        ExportPSDaverageCheckBox        matlab.ui.control.CheckBox
        ExportPSDCheckBox               matlab.ui.control.CheckBox
        ExportedgesCheckBox             matlab.ui.control.CheckBox
        AutomaticfeaturerecognitionButton  matlab.ui.control.Button
        ImageLoadingProgress            matlab.ui.control.Label
        averageCheckBox                 matlab.ui.control.CheckBox
        ExportMatlabFIGButton           matlab.ui.control.Button
        dataTable_Contacts              matlab.ui.control.Table
        ShowellipticalfitCheckBox       matlab.ui.control.CheckBox
        PauseButton                     matlab.ui.control.StateButton
        MetricButtonGroup               matlab.ui.container.ButtonGroup
        LERtrailButton                  matlab.ui.control.RadioButton
        LERleadButton                   matlab.ui.control.RadioButton
        LERButton                       matlab.ui.control.RadioButton
        LWRButton                       matlab.ui.control.RadioButton
        StopButton                      matlab.ui.control.Button
        AnalysisprogressGauge           matlab.ui.control.LinearGauge
        AnalysisprogressGaugeLabel      matlab.ui.control.Label
        ShowerrorsButton                matlab.ui.control.StateButton
        ExportdataButton                matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        UnselectallButton               matlab.ui.control.Button
        ProcessimagesButton             matlab.ui.control.Button
        ImagesFolderButton              matlab.ui.control.Button
        dataTable                       matlab.ui.control.Table
        DataDisplayButtonGroup          matlab.ui.container.ButtonGroup
        HDCFButton                      matlab.ui.control.RadioButton
        PSDButton                       matlab.ui.control.RadioButton
        LCDUButton                      matlab.ui.control.RadioButton
        ContourMetricButtonGroup        matlab.ui.container.ButtonGroup
        NoneButton_2                    matlab.ui.control.RadioButton
        AngleButton                     matlab.ui.control.RadioButton
        FitstderrorButton               matlab.ui.control.RadioButton
        EllipticityButton               matlab.ui.control.RadioButton
        RadiusButton                    matlab.ui.control.RadioButton
        Image                           matlab.ui.control.UIAxes
        Colorbar                        matlab.ui.control.UIAxes
        Metric                          matlab.ui.control.UIAxes
        ParametersTab                   matlab.ui.container.Tab
        ContactspecificparametersPanel  matlab.ui.container.Panel
        ExcludeedgesCheckBox            matlab.ui.control.CheckBox
        RadiusfractionEditField         matlab.ui.control.NumericEditField
        RadiusfractionEditFieldLabel    matlab.ui.control.Label
        ContactsubimagesizeEditField    matlab.ui.control.NumericEditField
        ContactsubimagesizeEditFieldLabel  matlab.ui.control.Label
        ContourdetectionButtonGroup     matlab.ui.container.ButtonGroup
        EdgefitfunctionButton           matlab.ui.control.ToggleButton
        FilteredimageButton             matlab.ui.control.ToggleButton
        DenoiseYEditField               matlab.ui.control.NumericEditField
        DenoiseYEditFieldLabel          matlab.ui.control.Label
        DenoiseXEditField               matlab.ui.control.NumericEditField
        DenoiseXEditFieldLabel          matlab.ui.control.Label
        SaveparametersButton            matlab.ui.control.Button
        LoadparametersButton            matlab.ui.control.Button
        RoughnessPowerSpectralDensityfitPanel  matlab.ui.container.Panel
        NumberoftapersEditField         matlab.ui.control.NumericEditField
        NumberoftapersEditFieldLabel    matlab.ui.control.Label
        TaperfunctionsDropDown          matlab.ui.control.DropDown
        TaperfunctionsDropDownLabel     matlab.ui.control.Label
        MultitaperButton                matlab.ui.control.StateButton
        HighfrequencyexclusionEditField  matlab.ui.control.NumericEditField
        HighfrequencyexclusionEditFieldLabel  matlab.ui.control.Label
        LowfrequencyexclusionEditField  matlab.ui.control.NumericEditField
        LowfrequencyexclusionEditFieldLabel  matlab.ui.control.Label
        HighfrequencyaverageEditField   matlab.ui.control.NumericEditField
        HighfrequencyaverageEditFieldLabel  matlab.ui.control.Label
        LowfrequencyaverageEditField    matlab.ui.control.NumericEditField
        LowfrequencyaverageEditFieldLabel  matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        CorrelationfrequencyEditField   matlab.ui.control.NumericEditField
        CorrelationfrequencyEditFieldLabel  matlab.ui.control.Label
        MaximumfunctionevaluationsEditField  matlab.ui.control.NumericEditField
        MaximumfunctionevaluationsEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofiterationsEditField  matlab.ui.control.NumericEditField
        MaximumnumberofiterationsEditFieldLabel  matlab.ui.control.Label
        PSDmodelDropDown                matlab.ui.control.DropDown
        PSDmodelDropDownLabel           matlab.ui.control.Label
        EdgedetectionPanel              matlab.ui.container.Panel
        BrightedgeselectionButtonGroup  matlab.ui.container.ButtonGroup
        OuterButton                     matlab.ui.control.RadioButton
        InnerButton                     matlab.ui.control.RadioButton
        CenterButton_2                  matlab.ui.control.RadioButton
        MulticoreCheckBox               matlab.ui.control.CheckBox
        EdgerangeButton                 matlab.ui.control.Button
        EdgedetectionrangeEditField     matlab.ui.control.NumericEditField
        EdgedetectionrangeEditFieldLabel  matlab.ui.control.Label
        ThresholdrangeEditField         matlab.ui.control.NumericEditField
        ThresholdrangeEditFieldLabel    matlab.ui.control.Label
        SEMmodelDropDown                matlab.ui.control.DropDown
        SEMmodelDropDownLabel           matlab.ui.control.Label
        PixelsizenmEditField            matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel       matlab.ui.control.Label
        MinpeakprominenceEditField      matlab.ui.control.NumericEditField
        MinpeakprominenceEditFieldLabel  matlab.ui.control.Label
        MinpeakdistanceEditField        matlab.ui.control.NumericEditField
        MinpeakdistanceEditFieldLabel   matlab.ui.control.Label
        MaxspikeEditField               matlab.ui.control.NumericEditField
        MaxspikeEditFieldLabel          matlab.ui.control.Label
        CDfractionEditField             matlab.ui.control.NumericEditField
        CDfractionEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        EdgefitfunctionButtonGroup      matlab.ui.container.ButtonGroup
        BrightedgeCheckBox              matlab.ui.control.CheckBox
        ThresholdButton                 matlab.ui.control.ToggleButton
        PolynomialButton                matlab.ui.control.ToggleButton
        LinearButton_2                  matlab.ui.control.ToggleButton
        PinchingthresholdEditField      matlab.ui.control.NumericEditField
        PinchingthresholdEditFieldLabel  matlab.ui.control.Label
        BridgingthresholdEditField      matlab.ui.control.NumericEditField
        BridgingthresholdEditFieldLabel  matlab.ui.control.Label
        ImagetoneButtonGroup            matlab.ui.container.ButtonGroup
        UndefinedButton                 matlab.ui.control.RadioButton
        NegativeButton                  matlab.ui.control.RadioButton
        PositiveButton                  matlab.ui.control.RadioButton
        ImagepreprocessingPanel         matlab.ui.container.Panel
        ManualrotationangleEditField    matlab.ui.control.NumericEditField
        ManualrotationangleEditFieldLabel  matlab.ui.control.Label
        maxXpixEditField                matlab.ui.control.NumericEditField
        maxYpixEditField                matlab.ui.control.NumericEditField
        maxYpixEditFieldLabel           matlab.ui.control.Label
        minYpixEditField                matlab.ui.control.NumericEditField
        maxXpixEditField_4Label         matlab.ui.control.Label
        APSmoothingEditField            matlab.ui.control.NumericEditField
        APSmoothingEditFieldLabel       matlab.ui.control.Label
        SetROIButton                    matlab.ui.control.Button
        minXpixEditField                matlab.ui.control.NumericEditField
        minXpixEditFieldLabel           matlab.ui.control.Label
        maxXpixEditField_2Label         matlab.ui.control.Label
        GradientcorrectionButtonGroup   matlab.ui.container.ButtonGroup
        CubicButton                     matlab.ui.control.ToggleButton
        QuadraticButton                 matlab.ui.control.ToggleButton
        LinearButton                    matlab.ui.control.ToggleButton
        NoneButton                      matlab.ui.control.ToggleButton
        RemovedistortionCheckBox        matlab.ui.control.CheckBox
        AutorotationalignmentCheckBox   matlab.ui.control.CheckBox
        ManualrotationadjustmentdegSlider  matlab.ui.control.Slider
        ManualrotationadjustmentdegSliderLabel  matlab.ui.control.Label
        ImageParameters                 matlab.ui.control.UIAxes
        MessagelogTab                   matlab.ui.container.Tab
        ClearlogButton                  matlab.ui.control.Button
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextAreaLabel           matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = private)
        Deployment = 'none' % Set to 'Win' to compile on windows, set to 'none' otherwise
        Version = "2.3.3";
        Release = "2024/11/06";
        Author = "Iacopo Mochi";
    end
    methods (Access = private)

        function results = collectParameters(app)
            prop = properties(app);
            results  = struct();
            for n = 1:length(prop)
                Pname = prop{n};
                if isprop(app.(Pname),'Value')
                    results.(Pname).Value = app.(Pname).Value;
                end
                if isprop(app.(Pname),'Text')
                    results.(Pname).Text = app.(Pname).Text;
                end
                if isprop(app.(Pname),'Tooltip')
                    results.(Pname).Tooltip = app.(Pname).Tooltip;
                end
            end

        end

        function restoreParameters(app,parameters)

            %To be added...

        end

        function R2_dataTable(app,R,n)
            m = mean(R.PSD(2:end));
            y = R.PSD(2:end);
            f = R.PSD_LWR_fit(2:end);
            R2_LWR = 1-sum((y-f).^2)/sum((y-m).^2);
            m = mean(R.PSD_LER(2:end));
            y = R.PSD_LER(2:end);
            f = R.PSD_LER_fit(2:end);
            R2_LER = 1-sum((y-f).^2)/sum((y-m).^2);
            m = mean(R.PSD_LERl(2:end));
            y = R.PSD_LERl(2:end);
            f = R.PSD_LERl_fit(2:end);
            R2_LERl = 1-sum((y-f).^2)/sum((y-m).^2);
            m = mean(R.PSD_LERt(2:end));
            y = R.PSD_LERt(2:end);
            f = R.PSD_LERt_fit(2:end);
            R2_LERt = 1-sum((y-f).^2)/sum((y-m).^2);

            R2_LWR = max([R2_LWR,0]);
            R2_LWR = min([R2_LWR,1]);
            R2_LER = max([R2_LER,0]);
            R2_LER = min([R2_LER,1]);
            R2_LERl = max([R2_LERl,0]);
            R2_LERl = min([R2_LERl,1]);
            R2_LERt = max([R2_LERt,0]);
            R2_LERt = min([R2_LERt,1]);

            if R2_LWR<0.5
                C_LWR = [1 1 1];
            else
                C_LWR = [0 0 0];
            end
            if R2_LER<0.5
                C_LER = [1 1 1];
            else
                C_LER = [0 0 0];
            end
            if R2_LERl<0.5
                C_LERl = [1 1 1];
            else
                C_LERl = [0 0 0];
            end
            if R2_LERt<0.5
                C_LERt = [1 1 1];
            else
                C_LERt = [0 0 0];
            end

            sLWR = uistyle("BackgroundColor",[R2_LWR,R2_LWR, 1],'FontColor',C_LWR);
            sLER = uistyle("BackgroundColor",[R2_LER,R2_LER, 1],'FontColor',C_LER);
            sLERl = uistyle("BackgroundColor",[R2_LERl,R2_LERl, 1],'FontColor',C_LERl);
            sLERt = uistyle("BackgroundColor",[R2_LERt,R2_LERt, 1],'FontColor',C_LERt);

            addStyle(app.dataTable,sLWR,"cell",[n,10])
            addStyle(app.dataTable,sLWR,"cell",[n,11])
            addStyle(app.dataTable,sLER,"cell",[n,14])
            addStyle(app.dataTable,sLER,"cell",[n,15])
            addStyle(app.dataTable,sLERl,"cell",[n,18])
            addStyle(app.dataTable,sLERl,"cell",[n,19])
            addStyle(app.dataTable,sLERt,"cell",[n,22])
            addStyle(app.dataTable,sLERt,"cell",[n,23])
        end

        function exportData(app)

            %%%%
            if ispc
                sl = '\';
            else
                sl = '/';
            end

            switch app.Deployment
                case 'Win'
                    if ~exist([char(getenv('LocalAppData')) sl 'SMILE'],'dir')
                        mkdir([char(getenv('LocalAppData')) sl 'SMILE'])
                    end
                    appdatafolder = [char(getenv('LocalAppData')) sl 'SMILE'];
                    if ~exist([appdatafolder 'parameters'],'dir')
                        mkdir([appdatafolder 'parameters'])
                    end
                    if ~exist([appdatafolder 'parameters' 'sl' 'AppData'],'dir')
                        mkdir([appdatafolder 'parameters' sl 'AppData'])
                    end
                    if exist([appdatafolder 'parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load([appdatafolder 'parameters' sl 'AppData' sl 'appData.mat']);
                    end
                case 'none'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                    end
                    if ~exist(['parameters' sl 'AppData'],'dir')
                        mkdir(['parameters' sl 'AppData'])
                    end
                    if exist(['parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load(['parameters' sl 'AppData' sl 'appData.mat']);
                    end
                case 'Mac'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                    end
                    if ~exist(['parameters' sl 'AppData'],'dir')
                        mkdir(['parameters' sl 'AppData'])
                    end
                    if exist(['parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load(['parameters' sl 'AppData' sl 'appData.mat']);
                    end
            end
            if ~exist('ExportDataFolder','var')
                ExportDataFolder = '';
            end

            %%%%


            About = table(app.Version,app.Release,app.Author,...
                'VariableNames',{'Version','Release date','Author'});
            Text = 'Export data';
            Color = [0.96,0.96,0.96];

            if app.BatchmodeCheckBox.Value
                dataStructure = app.ExportdataButton.UserData;
                ExportDataFolder = dataStructure.folder;
                ExportDataFolder = ExportDataFolder{1};
                pathname = ExportDataFolder;
                filename = 'Output.xlsx';
            else
                [filename, pathname] = uiputfile([ExportDataFolder '*.*']);
                ExportDataFolder = pathname;
            end
            %Overwrite
            if exist([pathname filename],'file')
                delete([pathname filename])
            end

            app.ExportdataButton.Text = 'Saving...';
            app.ExportdataButton.BackgroundColor = [0.7,0.7,1];
            drawnow;
            filename = [pathname filename];
            D = app.dataTable.Data;
            Dc = app.dataTable_Contacts.Data;
            DS = app.ExportdataButton.UserData;
            Ddata =  struct();
            if ~isempty(D)
                Ddata.PSD_LWR = DS.PSD_LWR;
                Ddata.PSD_LWR_unbiased = DS.PSD_LWR_unbiased;
                Ddata.PSD_LWR_fit = DS.PSD_LWR_fit;
                Ddata.PSD_LWR_fit_unbiased = DS.PSD_LWR_fit_unbiased;
                Ddata.PSD_LER = DS.PSD_LER;
                Ddata.PSD_LER_unbiased = DS.PSD_LER_unbiased;
                Ddata.PSD_LER_fit = DS.PSD_LER_fit;
                Ddata.PSD_LER_fit_unbiased = DS.PSD_LER_fit_unbiased;
                Ddata.PSD_LERl = DS.PSD_LERl;
                Ddata.PSD_LERl_unbiased = DS.PSD_LERl_unbiased;
                Ddata.PSD_LERl_fit = DS.PSD_LERl_fit;
                Ddata.PSD_LERl_fit_unbiased = DS.PSD_LERl_fit_unbiased;
                Ddata.PSD_LERt = DS.PSD_LERt;
                Ddata.PSD_LERt_unbiased = DS.PSD_LERt_unbiased;
                Ddata.PSD_LERt_fit = DS.PSD_LERt_fit;
                Ddata.PSD_LERt_fit_unbiased = DS.PSD_LERt_fit_unbiased;
                Ddata.LeadingEdges = DS.ProfilesLFilled;
                Ddata.TrailingEdges = DS.ProfilesRFilled;
                Ddata.LineCenters = DS.LinesCenters;
                Ddata.Frequency = DS.freq;

                Ddata.PSD_LWR_a = DS.PSD_LWR_a;
                Ddata.PSD_LWR_unbiased_a = DS.PSD_LWR_unbiased_a;
                Ddata.PSD_LWR_fit_a = DS.PSD_LWR_fit_a;
                Ddata.PSD_LWR_fit_unbiased_a = DS.PSD_LWR_fit_unbiased_a;
                Ddata.PSD_LER_a = DS.PSD_LER_a;
                Ddata.PSD_LER_unbiased_a = DS.PSD_LER_unbiased_a;
                Ddata.PSD_LER_fit_a = DS.PSD_LER_fit_a;
                Ddata.PSD_LER_fit_unbiased_a = DS.PSD_LER_fit_unbiased_a;
                Ddata.PSD_LERl_a = DS.PSD_LERl_a;
                Ddata.PSD_LERl_unbiased_a = DS.PSD_LERl_unbiased_a;
                Ddata.PSD_LERl_fit_a = DS.PSD_LERl_fit_a;
                Ddata.PSD_LERl_fit_unbiased_a = DS.PSD_LERl_fit_unbiased_a;
                Ddata.PSD_LERt_a = DS.PSD_LERt_a;
                Ddata.PSD_LERt_unbiased_a = DS.PSD_LERt_unbiased_a;
                Ddata.PSD_LERt_fit_a = DS.PSD_LERt_fit_a;
                Ddata.PSD_LERt_fit_unbiased_a = DS.PSD_LERt_fit_unbiased_a;

                Ddata.Frequency_a = DS.freq_a;
            end
            Ddata.PS = DS.PS;
            clear('DS')
            D = cell2table(D);
            Dc = cell2table(Dc);

            AllParms = app.ParametersTab.UserData;
            %%%%%%%%%%            AllParms
            parameters = gatherParameters(app);

            %%Lines
            VD = app.dataTable.ColumnName;
            VN = VD;
            for n = 1:numel(VD)
                a = VN{n};
                a(isspace(a))='_';
                VN{n} = a;
            end
            if ~isempty(D)
                L = length(VN);

                FN = fieldnames(parameters);
                cntp = 0;
                cntc = size(D,2);
                for kk = 1:length(FN)

                    cntp = cntp+1;
                    VD{L+cntp} = FN{kk};
                    VN{L+cntp} = FN{kk};
                    parm = cell(size(D,1),1);
                    for n = 1:(size(D,1)-1)
                        p = AllParms{n};
                        if ~isempty(p)
                            parm{n} = p.(FN{kk});
                            %%% Dirty fix to export the pixel size
                            if strcmp(FN{kk},'ps')
                                parm{n} = parameters.ps;
                            end
                            %%%
                        else
                            parm{n} = [];
                        end
                    end
                    D.(['D' num2str(cntc+kk)]) = parm;
                end
            end
            D.Properties.VariableDescriptions = VD;
            D.Properties.VariableNames = VN;

            %%contacts
            VD = app.dataTable_Contacts.ColumnName;
            VN = VD;
            for n = 1:numel(VD)
                a = VN{n};
                a(isspace(a))='_';
                VN{n} = a;
            end

            L = length(VN);
            if ~isempty(Dc)
                FN = fieldnames(parameters);
                cntp = 0;
                cntc = size(D,2);
                for kk = 1:length(FN)

                    cntp = cntp+1;
                    VD{L+cntp} = FN{kk};
                    VN{L+cntp} = FN{kk};
                    parm = cell(size(D,1),1);
                    for n = 1:size(Dc,1)
                        p = AllParms{n};
                        if ~isempty(p)
                            parm{n} = p.(FN{kk});
                            %%% Dirty fix to export the pixel size
                            if strcmp(FN{kk},'ps')
                                parm{n} = parameters.ps;
                            end
                            %%%
                        else
                            parm{n} = [];
                        end
                    end
                    Dc.(['Dc' num2str(cntc+kk)]) = parm(:);
                end
            end
            Dc.Properties.VariableDescriptions = VD;
            Dc.Properties.VariableNames = VN;

            if strcmpi(filename(end-2:end),'mat')
                save([filename],'D','Dc','Ddata')
            elseif strcmpi(filename(end-3:end),'xlsx')
                fnames = D.File_Name;
                fnamesc = Dc.File_Name;
                if ~isempty(fnames)
                    writecell(fnames(1:end-1,1),filename,'FileType','Spreadsheet',...
                        'Sheet','Image List');
                end
                if ~isempty(fnamesc)
                    writecell(fnamesc,filename,'FileType','Spreadsheet',...
                        'Sheet','Image List','WriteMode','append');
                end
                if ~isempty(D)
                    writetable(D,filename,'FileType','Spreadsheet',...
                        'Sheet','Lines Data')
                    if app.ExportedgesCheckBox.Value==1
                        A = Ddata.LeadingEdges;
                        for n = 1:size(A,2)
                            writematrix(A{1,n}*Ddata.PS(n),filename,'FileType','Spreadsheet',...
                                'Sheet',['Image ' num2str(n) ' Leading edges' ]);
                        end
                        B = Ddata.TrailingEdges;
                        for n = 1:size(B,2)
                            writematrix(B{1,n}*Ddata.PS(n),filename,'FileType','Spreadsheet',...
                                'Sheet',['Image ' num2str(n) ' Trailing edges' ]);
                        end
                    end
                    if app.ExportPSDCheckBox.Value==1
                        labels = {'Frequency','PSD LWR','PSD LWR unbiased','PSD LWR fit','PSD LWR fit unbiased',...
                            'PSD LER','PSD LER unbiased','PSD LER fit','PSD LER fit unbiased',...
                            'PSD LERl','PSD LERl unbiased','PSD LERl fit','PSD LERl fit unbiased',...
                            'PSD LERt','PSD LERt unbiased','PSD LERt fit','PSD LERt fit unbiased'};
                        for n = 1:size(Ddata.Frequency,2)
                            sheetName = ['Image ' num2str(n) ' PSD'];
                            writecell(labels,filename,'FileType','spreadsheet',...
                                'Sheet',sheetName)

                            datamatrix = zeros(max(size(Ddata.Frequency{n})),13);
                            datamatrix(:,1) = Ddata.Frequency{n}';
                            datamatrix(:,2) = Ddata.PSD_LWR{n};
                            datamatrix(:,3) = Ddata.PSD_LWR_unbiased{n};
                            datamatrix(:,4) = Ddata.PSD_LWR_fit{n};
                            datamatrix(:,5) = Ddata.PSD_LWR_fit_unbiased{n};
                            datamatrix(:,6) = Ddata.PSD_LER{n};
                            datamatrix(:,7) = Ddata.PSD_LER_unbiased{n};
                            datamatrix(:,8) = Ddata.PSD_LER_fit{n};
                            datamatrix(:,9) = Ddata.PSD_LER_fit_unbiased{n};
                            datamatrix(:,10) = Ddata.PSD_LERl{n};
                            datamatrix(:,11) = Ddata.PSD_LERl_unbiased{n};
                            datamatrix(:,12) = Ddata.PSD_LERl_fit{n};
                            datamatrix(:,13) = Ddata.PSD_LERl_fit_unbiased{n};
                            datamatrix(:,14) = Ddata.PSD_LERt{n};
                            datamatrix(:,15) = Ddata.PSD_LERt_unbiased{n};
                            datamatrix(:,16) = Ddata.PSD_LERt_fit{n};
                            datamatrix(:,17) = Ddata.PSD_LERt_fit_unbiased{n};
                            writematrix(datamatrix,filename,'FileType','spreadsheet',...
                                'Sheet',sheetName,'Range','A2')

                        end
                    end
                    if app.ExportPSDaverageCheckBox.Value==1
                        labels = {'Frequency','PSD LWR','PSD LWR unbiased','PSD LWR fit','PSD LWR fit unbiased',...
                            'PSD LER','PSD LER unbiased','PSD LER fit','PSD LER fit unbiased',...
                            'PSD LERl','PSD LERl unbiased','PSD LERl fit','PSD LERl fit unbiased',...
                            'PSD LERt','PSD LERt unbiased','PSD LERt fit','PSD LERt fit unbiased'};
                        sheetName = 'Average PSD';
                        writecell(labels,filename,'FileType','spreadsheet',...
                            'Sheet',sheetName)
                        datamatrix = zeros(max(size(Ddata.Frequency_a)),13);
                        datamatrix(:,1) = Ddata.Frequency_a';
                        datamatrix(:,2) = Ddata.PSD_LWR_a;
                        datamatrix(:,3) = Ddata.PSD_LWR_unbiased_a;
                        datamatrix(:,4) = Ddata.PSD_LWR_fit_a;
                        datamatrix(:,5) = Ddata.PSD_LWR_fit_unbiased_a;
                        datamatrix(:,6) = Ddata.PSD_LER_a;
                        datamatrix(:,7) = Ddata.PSD_LER_unbiased_a;
                        datamatrix(:,8) = Ddata.PSD_LER_fit_a;
                        datamatrix(:,9) = Ddata.PSD_LER_fit_unbiased_a;
                        datamatrix(:,10) = Ddata.PSD_LERl_a;
                        datamatrix(:,11) = Ddata.PSD_LERl_unbiased_a;
                        datamatrix(:,12) = Ddata.PSD_LERl_fit_a;
                        datamatrix(:,13) = Ddata.PSD_LERl_fit_unbiased_a;
                        datamatrix(:,14) = Ddata.PSD_LERt_a;
                        datamatrix(:,15) = Ddata.PSD_LERt_unbiased_a;
                        datamatrix(:,16) = Ddata.PSD_LERt_fit_a;
                        datamatrix(:,17) = Ddata.PSD_LERt_fit_unbiased_a;
                        writematrix(datamatrix,filename,'FileType','spreadsheet',...
                            'Sheet',sheetName,'Range','A2')
                    end
                end
                if ~isempty(Dc)
                    writetable(Dc,filename,'FileType','Spreadsheet',...
                        'Sheet','Contacts Data')
                    if app.ExportcontactdetailsCheckBox.Value == 1
                        fnamesc = Dc.File_Name;
                        for ncimage = 1:length(fnamesc)
                            sheetName = ['Image ' num2str(n) '_CNT_profiles'];
                            dataStructure = app.ExportdataButton.UserData;
                            % X profiles
                            CNT_Profiles_X = dataStructure.ContactsContoursXs(ncimage);
                            % Y profiles
                            CNT_Profiles_Y = dataStructure.ContactsContoursYs(ncimage);
                            for ndef = 1:length(CNT_Profiles_X)
                                cnx = CNT_Profiles_X{ndef};
                                cny = CNT_Profiles_Y{ndef};
                                cnt_Cell = cell(cnx(:), cny(:)))
                                for point = 1:length(cnx)
                                    writecell(ones(10),filename,'FileType','spreadsheet',...
                                        'Sheet',sheetName)
                                end
                            writematrix(ones(10),filename,'FileType','spreadsheet',...
                                'Sheet',sheetName)
                            sheetName = ['Image ' num2str(n) '_CNT_metrics'];
                            writematrix(ones(10),filename,'FileType','spreadsheet',...
                                'Sheet',sheetName)
                        end
                    end
                end
                writetable(About,filename,'Filetype','Spreadsheet',...
                    'Sheet','About','WriteVariableNames',true)
            else %Comma separated values
                fnames = D.File_Name;
                fnamesc = Dc.File_Name;
                writetable(About,filename,"FileType","Text",'WriteVariableNames',true);
                if ~isempty(D)
                    writecell(fnames(1:end-1,1),filename,'FileType','Text',...
                        'Delimiter',',','WriteMode','append');
                end
                if ~isempty(Dc)
                    writecell(fnamesc,filename,'FileType','Text',...
                        'Delimiter',',','WriteMode','append');
                end
                if ~isempty(D)
                    writetable(D,filename,'FileType','Text','WriteMode','append','WriteVariableNames',true)

                end
                if ~isempty(Dc)
                    writetable(Dc,filename,"FileType","Text",'WriteMode','append','WriteVariableNames',true);
                end
            end

            app.ExportdataButton.Text = Text;
            app.ExportdataButton.BackgroundColor = Color;


            app.ExportdataButton.Text = Text;
            app.ExportdataButton.BackgroundColor = Color;
            if ~exist('folder','var')
                folder = '';
            end
            switch app.Deployment
                case 'Win'
                    appdatafolder = [char(getenv('LocalAppData')) sl 'SMILE'];
                    save([appdatafolder 'parameters' sl 'AppData' sl 'appData.mat'],'folder','ExportDataFolder')

                case 'none'
                    save(['parameters' sl 'AppData' sl 'appData.mat'],'folder','ExportDataFolder')

                case 'Mac'
                    save(['parameters' sl 'AppData' sl 'appData.mat'],'folder','ExportDataFolder')
            end

        end


        function Output = SEM_line_Edge_detect(app,parameters)
            %filename = parameters.filename;
            MinPeakProminence = parameters.MinPeakProminence; %0.36
            MinPeakDistance = parameters.MinPeakDistance; %40


            ps = parameters.ps{1};

            A = double(parameters.rawImages);

            h1 = parameters.ROI_Y1;
            h2 = parameters.ROI_Y2;
            w1 = parameters.ROI_X1;
            w2 = parameters.ROI_X2;


            %Cropped image used to calculate the rotation angle
            Ac = A(h1:h2,w1:w2);

            if parameters.rotation == true

                %2-step radon transform for image alignment

                alpha0 = -5:0.05:5;
                sp = size(Ac);
                [x,y] = meshgrid(-sp(2)/2:sp(2)/(sp(2)-1):sp(2)/2,-sp(1)/2:sp(1)/(sp(1)-1):sp(1)/2);
                ra = min(sp/2);
                P = (x.^2+y.^2)<ra^2;
                R = radon(Ac.*P,alpha0);
                [~,id] = max(sum(R.^2));
                alpha1 = alpha0(id)-0.25:0.01:alpha0(id)+0.25;
                R = radon(Ac.*P,alpha1);
                [~,id] = max(sum(R.^2));

                %Rotation correction
                alpha = -alpha1(id);
                %A = imresize(A,2,'lanczos3');
                Ar = imrotate(A,alpha,'nearest','crop');
                %Ar = imrotate(A,alpha,'bicubic','crop');
                %Ar = imresize(Ar,0.5,'lanczos3');

            else
                Ar = A;
            end
            %Image filtering
            s1 = size(A,1);
            s2 = size(A,2);
            sx = parameters.sx;
            sy = parameters.sy;

            [x,y] = meshgrid(-s1/2:s1/(s1-1):s1/2,-s2/2:s2/(s2-1):s2/2);
            if sx>0 && sy>0
                G = exp(-x.^2/(2*sx^2)-y.^2/(2*sy^2));
                G1 = G(round(s2/2-5*sy):round(s2/2+5*sy),round(s1/2-5*sx):round(s1/2+5*sx));
                Arf = conv2(Ar,G1','same');
            elseif sx>0 && sy<=0
                G = exp(-x.^2/(2*sx^2));
                Arf = conv2(Ar,G','same');
            elseif sy>0 && sx<=0
                G = exp(-y.^2/(2*sy^2));
                Arf = conv2(Ar,G','same');
            else
                Arf = Ar;
            end

            %Cropping
            Arc = Arf(h1:h2,w1:w2);
            %Arc_nf = Ar(h1:h2,w1:w2);


            Arcc = Arc;
            id = Arc<mean(Arc(:));
            Arcc(id) = nan;
            % M = nanmean(Arcc,2);


            %% flattening image
            flattening = parameters.RemoveGradient;

            [xfit,yfit] = meshgrid(1:size(Arcc,2),1:size(Arcc,1));
            xfit = xfit-size(xfit,2)/2;
            yfit = yfit-size(yfit,1)/2;
            for n = 1:2
                id = ~isnan(Arcc);
                if strcmp(flattening,'Linear')
                    F = fit([xfit(id),yfit(id)],Arcc(id),'poly11');
                    F = F(xfit,yfit);
                    ArcF = normal(Arc-F);
                elseif strcmp(flattening,'Quadratic')
                    F = fit([xfit(id),yfit(id)],Arcc(id),'poly22');
                    F = F(xfit,yfit);
                    ArcF = normal(Arc-F);
                elseif strcmp(flattening,'Cubic')
                    F = fit([xfit(id),yfit(id)],Arcc(id),'poly33');
                    F = F(xfit,yfit);
                    ArcF = normal(Arc-F);
                else
                    ArcFm = medfilt2(Arc,[5,5]);
                    ArcF = (Arc-min(ArcFm(:)))./(max(ArcFm(:))-min(ArcFm(:)));
                    F = ones(size(Arc));
                end
                Arc = ArcF;
                Arcc = ArcF;
            end


            ArcF = (ArcF-(median(min(ArcF,[],2))))./((median(max(ArcF,[],2)))-(median(min(ArcF,[],2))));
            ArcF(ArcF<0)=0;
            Sb = mean(ArcF);
            %%%%Experimental binarize image for edge location detection
            % ArcB=ArcF;
            %
            % ArcB(ArcF<mean(ArcF(:)))=0;
            % ArcB(ArcF>=mean(ArcF(:)))=1;
            % Sb = mean(ArcB);


            %Create a filtered version of the image
            %Arcf = wiener2(ArcF,[1,5]);

            %Arcf = Arc;
            %Arc = NLMean(Arc,3,0.001,5);

            %S_nf = sum(Arc_nf);
            %Arc_nf = (Arc_nf-min(S_nf)/size(Arc_nf,1))./(max(S_nf)/size(Arc_nf,1)-min(S_nf)/size(Arc_nf,1));


            dS = smooth(diff(Sb));

            if ~app.BrightedgeCheckBox.Value
                dS = smooth(diff(Sb));
            else
                dS = Sb;
            end

            [~,edgelocations]= findpeaks(normal(abs(dS)),...
                'MinPeakProminence',MinPeakProminence,...
                'MinPeakDistance',MinPeakDistance);
            leadingEdges = [];
            trailingEdges = [];
            if ~app.BrightedgeCheckBox.Value
                for n = 1:length(edgelocations)
                    if app.ImagetoneButtonGroup.Buttons(2).Value
                        if dS(edgelocations(n))>0
                            trailingEdges = [trailingEdges edgelocations(n)];
                        else
                            leadingEdges = [leadingEdges edgelocations(n)];
                        end
                    else
                        if dS(edgelocations(n))<0
                            trailingEdges = [trailingEdges edgelocations(n)];
                        else
                            leadingEdges = [leadingEdges edgelocations(n)];
                        end
                    end
                end
            else
                mdiff = round(mean(diff(edgelocations))/2);
                for n = 1:length(edgelocations)
                    if edgelocations(n)>mdiff
                        intvleft = mdiff;
                    else
                        intvleft = edgelocations(n)-1;
                    end
                    if (length(dS)-edgelocations(n))>mdiff
                        intvright = mdiff;
                    else
                        intvright = length(dS)-edgelocations(n)-1;
                    end
                    lval = mean(Sb(edgelocations(n)-intvleft:edgelocations(n)));
                    rval = mean(Sb(edgelocations(n):edgelocations(n)+intvright));
                   
                    if app.ImagetoneButtonGroup.Buttons(2).Value

                        if rval>=lval
                            trailingEdges = [trailingEdges edgelocations(n)];
                        else
                            leadingEdges = [leadingEdges edgelocations(n)];
                        end
                    elseif app.ImagetoneButtonGroup.Buttons(1).Value
                        if rval<lval
                            trailingEdges = [trailingEdges edgelocations(n)];
                        else
                            leadingEdges = [leadingEdges edgelocations(n)];
                        end
                    else
                        if mod(n,2) == 0
                            trailingEdges = [trailingEdges edgelocations(n)];
                        else
                            leadingEdges = [leadingEdges edgelocations(n)];
                        end
                    end
                end
            end
            %consider only complete lines
            newLeadingEdges = [];
            newTrailingEdges = [];
            for n = 1:length(leadingEdges)
                ve = trailingEdges>leadingEdges(n);
                id = find(ve,1);
                if ~isempty(id)
                    newLeadingEdges = [newLeadingEdges leadingEdges(n)];
                    newTrailingEdges = [newTrailingEdges trailingEdges(id)];
                end
            end
            leadingEdges = newLeadingEdges;
            trailingEdges = newTrailingEdges;


            averageCD = mean(trailingEdges-leadingEdges);
            averagePitch = ps*mean([diff(trailingEdges) diff(leadingEdges)]);
            linesCenters = (trailingEdges+leadingEdges)/2;

            CD = averageCD;

            %Edge detection threshold
            threshold = parameters.threshold;

            %Spike filtering
            ff = parameters.spikePeak;

            if strcmp(app.EdgerangeButton.Text,'Edge range')
                %Search the edge in a range of b*CD around the guessed position
                b = round(CD*parameters.CD_fraction);
            else
                b = app.EdgedetectionrangeEditField.Value;
            end
            %fwhmf = 3;
            if app.BrightedgeCheckBox.Value
                if app.MulticoreCheckBox.Value == 1
                    [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectfPoly2MC(app,leadingEdges,trailingEdges,ArcF,b,threshold);
                else
                    [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectfPoly2(app,leadingEdges,trailingEdges,ArcF,b,threshold);
                end
            else
                if app.EdgefitfunctionButtonGroup.Buttons(1).Value
                    [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectLin(leadingEdges,trailingEdges,ArcF,b,threshold);
                elseif app.EdgefitfunctionButtonGroup.Buttons(2).Value
                    if app.MulticoreCheckBox.Value == 1
                        [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectfPolyMC(leadingEdges,trailingEdges,ArcF,b,threshold);
                    else
                        [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectfPoly(leadingEdges,trailingEdges,ArcF,b,threshold);
                    end
                else app.EdgefitfunctionButtonGroup.Buttons(3).Value
                    %[leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectThreshold(leadingEdges,trailingEdges,ArcF,CD,b,threshold,fwhmf);
                    [leadingEdgeProfiles,trailingEdgeProfiles] = edgeDetectThreshold(leadingEdges,trailingEdges,ArcF,b,threshold);
                end

            end
            %Find undetected regions, bridge and pinch defects
            leadingEdgeProfilesUndetectedId = isnan(leadingEdgeProfiles);
            trailingEdgeProfilesUndetectedId = isnan(trailingEdgeProfiles);

            %Find spikes
            leadingEdgeProfilesSpikesId = ...
                (abs(diff(leadingEdgeProfiles(1:end-1,:))) > ff) &...
                (abs(diff(leadingEdgeProfiles(2:end,:))) > ff);
            leadingEdgeProfilesSpikesId = [false(1,size(leadingEdgeProfilesSpikesId,2));leadingEdgeProfilesSpikesId;false(1,size(leadingEdgeProfilesSpikesId,2))];
            trailingEdgeProfilesSpikesId = ...
                (abs(diff(trailingEdgeProfiles(1:end-1,:))) > ff) &...
                (abs(diff(trailingEdgeProfiles(2:end,:))) > ff);
            trailingEdgeProfilesSpikesId = [false(1,size(trailingEdgeProfilesSpikesId,2));trailingEdgeProfilesSpikesId;false(1,size(trailingEdgeProfilesSpikesId,2))];

            % if size(leadingEdgeProfiles,2)>1
            %     [~,leadingEdgeProfilesSpikesId] = gradient(leadingEdgeProfiles);
            % else
            %     leadingEdgeProfilesSpikesId = gradient(leadingEdgeProfiles);
            % end
            % if size(trailingEdgeProfiles,2)>1
            %     [~,trailingEdgeProfilesSpikesId] = gradient(trailingEdgeProfiles);
            % else
            %     trailingEdgeProfilesSpikesId = gradient(trailingEdgeProfiles);
            % end
            % leadingEdgeProfilesSpikesId = abs(leadingEdgeProfilesSpikesId)>ff;
            % trailingEdgeProfilesSpikesId = abs(trailingEdgeProfilesSpikesId)>ff;

            % %Substitute spikes with median profiles value
            %leadingEdgeProfiles(leadingEdgeProfilesSpikesId)=nan*median(leadingEdgeProfiles(~leadingEdgeProfilesSpikesId));
            %trailingEdgeProfiles(trailingEdgeProfilesSpikesId)=nan*median(trailingEdgeProfiles(~trailingEdgeProfilesSpikesId));

            for lep = 1:size(leadingEdgeProfiles,2)
                leadingEdgeProfiles(leadingEdgeProfilesSpikesId(:,lep),lep)=median(leadingEdgeProfiles(~leadingEdgeProfilesSpikesId(:,lep),lep));
            end
            for tep = 1:size(trailingEdgeProfiles,2)
                trailingEdgeProfiles(trailingEdgeProfilesSpikesId(:,tep),tep)=median(trailingEdgeProfiles(~trailingEdgeProfilesSpikesId(:,tep),tep));
            end
            pinchingThreshold = parameters.PinchingThreshold;
            pinchingId = (trailingEdgeProfiles-leadingEdgeProfiles)<=pinchingThreshold;

            bridgingThreshold = parameters.BridgingThreshold;
            bridgingId1 = false(size(leadingEdgeProfiles));
            bridgingId2 = false(size(leadingEdgeProfiles));
            bridgingIds = (leadingEdgeProfiles(:,2:end)-trailingEdgeProfiles(:,1:end-1))<=bridgingThreshold;
            bridgingId1(:,2:end) = bridgingIds;
            bridgingId2(:,1:end-1) = bridgingIds;

            %Substitute defective regions with average values
            leadingEdgeProfilesFilled = leadingEdgeProfiles;
            trailingEdgeProfilesFilled = trailingEdgeProfiles;

            %leadingEdgeProfilesFilled(leadingEdgeProfilesUndetectedId)=nan;
            leadingEdgeProfilesFilled(bridgingId2)=nan;
            leadingEdgeProfilesFilled(pinchingId)=nan;
            %trailingEdgeProfilesFilled(trailingEdgeProfilesUndetectedId)=nan;
            trailingEdgeProfilesFilled(bridgingId1)=nan;
            trailingEdgeProfilesFilled(pinchingId)=nan;

            % %Find spikes again
            % leadingEdgeProfilesSpikesId = abs(diff(leadingEdgeProfilesFilled,1)) > ff;
            % trailingEdgeProfilesSpikesId = abs(diff(trailingEdgeProfilesFilled,1)) > ff;
            % A = [false(1,size(leadingEdgeProfilesSpikesId,2));leadingEdgeProfilesSpikesId];
            % B = [leadingEdgeProfilesSpikesId;false(1,size(leadingEdgeProfilesSpikesId,2))];
            % leadingEdgeProfilesSpikesId = A | B;
            % A = [false(1,size(trailingEdgeProfilesSpikesId,2));trailingEdgeProfilesSpikesId];
            % B = [trailingEdgeProfilesSpikesId;false(1,size(trailingEdgeProfilesSpikesId,2))];
            % trailingEdgeProfilesSpikesId = A | B;
            %
            % leadingEdgeProfilesFilled(leadingEdgeProfilesSpikesId) = nan;
            % trailingEdgeProfilesFilled(trailingEdgeProfilesSpikesId) = nan;


            for n = 1:size(leadingEdgeProfiles,2)
                leadingEdgeProfilesFilled(isnan(leadingEdgeProfilesFilled(:,n)),n) = ...
                    nanmedian(leadingEdgeProfilesFilled(:,n));
                trailingEdgeProfilesFilled(isnan(trailingEdgeProfilesFilled(:,n)),n) = ...
                    nanmedian(trailingEdgeProfilesFilled(:,n));
            end

            %Create defective profiles

            leadingEdgeProfilesSpikes = leadingEdgeProfilesFilled*nan;
            leadingEdgeProfilesBridged = leadingEdgeProfilesFilled*nan;
            leadingEdgeProfilesPinched = leadingEdgeProfilesFilled*nan;
            leadingEdgeProfilesUndetected = leadingEdgeProfilesFilled*nan;
            trailingEdgeProfilesSpikes = trailingEdgeProfilesFilled*nan;
            trailingEdgeProfilesBridged = trailingEdgeProfilesFilled*nan;
            trailingEdgeProfilesPinched = trailingEdgeProfilesFilled*nan;
            trailingEdgeProfilesUndetected = trailingEdgeProfilesFilled*nan;

            leadingEdgeProfilesSpikes(leadingEdgeProfilesSpikesId) = leadingEdgeProfilesFilled(leadingEdgeProfilesSpikesId);
            trailingEdgeProfilesSpikes(trailingEdgeProfilesSpikesId) = trailingEdgeProfilesFilled(trailingEdgeProfilesSpikesId);
            leadingEdgeProfilesBridged(bridgingId1) = leadingEdgeProfilesFilled(bridgingId1);
            trailingEdgeProfilesBridged(bridgingId2) = trailingEdgeProfilesFilled(bridgingId2);
            leadingEdgeProfilesPinched(pinchingId) = leadingEdgeProfilesFilled(pinchingId);
            trailingEdgeProfilesPinched(pinchingId) = trailingEdgeProfilesFilled(pinchingId);
            leadingEdgeProfilesUndetected(leadingEdgeProfilesUndetectedId) = leadingEdgeProfilesFilled(leadingEdgeProfilesUndetectedId);
            trailingEdgeProfilesUndetected(trailingEdgeProfilesUndetectedId) = trailingEdgeProfilesFilled(trailingEdgeProfilesUndetectedId);


            CentersP = [];
            CentersB = [];
            % clustR = 0.5;
            % L = 1:size(trailingEdgeProfilesFilled,1);
            % for nn = 2:size(leadingEdgeProfiles,2)
            %     D = [L(pinchingId(:,nn))' (trailingEdgeProfilesFilled(pinchingId(:,nn),nn)+...
            %         leadingEdgeProfilesFilled(pinchingId(:,nn),nn))*0.5];
            %     if ~isempty(D)
            %         if size(D,1)>1 && sum(isnan(D(:)))==0
            %             centers = subclust(D,clustR);
            %             CentersP = [CentersP;centers];
            %         elseif sum(isnan(D(:)))==0
            %             CentersP = [CentersP;D];
            %         end
            %     end
            %
            %     D = [L(bridgingId1(:,nn))' (trailingEdgeProfilesFilled(bridgingId1(:,nn),nn)+...
            %         leadingEdgeProfilesFilled(bridgingId2(:,nn-1),nn-1))*0.5];
            %     if ~isempty(D)
            %         if size(D,1)>1 && sum(isnan(D(:)))==0
            %             centers = subclust(D,clustR);
            %             CentersB = [CentersB;centers];
            %         elseif sum(isnan(D(:)))==0
            %             CentersB = [CentersB;D];
            %         end
            %     end
            % end

            OLP = leadingEdgeProfilesFilled;
            OLT = trailingEdgeProfilesFilled;
            LW = OLT-OLP;

            Output = struct;
            Output.trailingEdgeProfiles = trailingEdgeProfiles;
            Output.leadingEdgeProfiles = leadingEdgeProfiles;
            Output.trailingEdgeProfilesFilled = trailingEdgeProfilesFilled;
            Output.leadingEdgeProfilesFilled = leadingEdgeProfilesFilled;
            Output.trailingEdgeProfilesBridged = trailingEdgeProfilesBridged;
            Output.leadingEdgeProfilesBridged = leadingEdgeProfilesBridged;
            Output.trailingEdgeProfilesPinched = trailingEdgeProfilesPinched;
            Output.leadingEdgeProfilesPinched = leadingEdgeProfilesPinched;
            Output.trailingEdgeProfilesSpikes = trailingEdgeProfilesSpikes;
            Output.leadingEdgeProfilesSpikes = leadingEdgeProfilesSpikes;
            Output.trailingEdgeProfilesUndetected = trailingEdgeProfilesUndetected;
            Output.leadingEdgeProfilesUndetected = leadingEdgeProfilesUndetected;
            Output.CentersP = CentersP;
            Output.CentersB = CentersB;
            Output.Pitch = averagePitch;

            Output.Arc = Arc;
            Output.F = F;
            Output.PS = ps;
            Output.LWvar = LW;


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispc
                s = '\';
            else
                s = '/';
            end
            diary off;
            if ispc
                appdatafolder = char(getenv('LocalAppData'));
                if ~isfolder([appdatafolder s 'SMILE'])
                    mkdir([appdatafolder s 'SMILE'])
                end
                if isfile([appdatafolder s 'SMILE' s 'SMILE2_msgLog.txt'])
                    delete([appdatafolder s 'SMILE' s 'SMILE2_msgLog.txt'])
                end
                diary([appdatafolder s 'SMILE' s 'SMILE2_msgLog.txt'])
                diary on
            else
                if isfile([pwd s 'SMILE2_msgLog.txt'])
                    delete([pwd s 'SMILE2_msgLog.txt'])
                end
                diary([pwd s 'SMILE2_msgLog.txt'])
                diary on
            end
        end

        % Button pushed function: ImagesFolderButton
        function ImagesFolderButtonPushed(app, event)

            %Populate table fields
            [Fields,Fields_C] = FieldNames(app);

            %ax = get(h.LinesTab,'userdata');
            %axp = get(h.ParametersTab,'userdata');

            if ispc
                sl = '\';
            else
                sl = '/';
            end

            switch app.Deployment
                case 'Win'
                    appdatafolder = [char(getenv('LocalAppData')) sl 'SMILE'];
                    if ~exist([appdatafolder sl 'parameters'],'dir')
                        mkdir([appdatafolder sl 'parameters'])
                        mkdir([appdatafolder sl 'parameters' sl 'AppData'])
                    else
                        if ~exist([appdatafolder sl 'parameters' sl 'AppData'],'dir')
                            mkdir([appdatafolder sl 'parameters' sl 'AppData'])
                        end
                    end

                    if exist([appdatafolder sl 'parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load([appdatafolder sl 'parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        if ischar(folder)
                            app.SMILE23UIFigure.Visible='off';
                            folder  = uigetdir(folder);
                            app.SMILE23UIFigure.Visible='on';
                            save([appdatafolder sl 'parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        else
                            app.SMILE23UIFigure.Visible='off';
                            folder = uigetdir;
                            app.SMILE23UIFigure.Visible='on';
                            save([appdatafolder sl 'parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        end
                    else
                        app.SMILE23UIFigure.Visible='off';
                        folder = uigetdir;
                        app.SMILE23UIFigure.Visible = 'on';
                        save([appdatafolder sl 'parameters' sl 'AppData' sl 'appData.mat'],'folder')
                    end
                case 'none'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                        mkdir(['parameters' sl 'AppData'])
                    else
                        if ~exist(['parameters' sl 'AppData'],'dir')
                            mkdir(['parameters' sl 'AppData'])
                        end
                    end

                    if exist(['parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        if ischar(folder)
                            app.SMILE23UIFigure.Visible='off';
                            folder  = uigetdir(folder);
                            app.SMILE23UIFigure.Visible = 'on';

                            save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        else
                            app.SMILE23UIFigure.Visible='off';
                            folder = uigetdir;
                            app.SMILE23UIFigure.Visible = 'on';
                            save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        end
                    else
                        app.SMILE23UIFigure.Visible='off';
                        folder = uigetdir;
                        app.SMILE23UIFigure.Visible = 'on';
                        save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                    end
                case 'Mac'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                        mkdir(['parameters' sl 'AppData'])
                    else
                        if ~exist(['parameters' sl 'AppData'],'dir')
                            mkdir(['parameters' sl 'AppData'])
                        end
                    end

                    if exist(['parameters' sl 'AppData' sl 'appData.mat'],'file')
                        load(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        if ischar(folder)
                            app.SMILE23UIFigure.Visible='off';
                            folder  = uigetdir(folder);
                            app.SMILE23UIFigure.Visible = 'on';

                            save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        else
                            app.SMILE23UIFigure.Visible='off';
                            folder = uigetdir;
                            app.SMILE23UIFigure.Visible = 'on';
                            save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                        end
                    else
                        app.SMILE23UIFigure.Visible='off';
                        folder = uigetdir;
                        app.SMILE23UIFigure.Visible = 'on';
                        save(['parameters' sl 'AppData' sl 'appData.mat'],'folder')
                    end
            end


            if isequal(folder,0)
                %Operation cancelled
            else
                folder = [folder sl];
                app.ImagesFolderButton.Tooltip = folder;

                d = dir([folder '*']);

                %%%%%
                %Finding folders with images

                dd = dir([folder '**' sl '*']);
                Folders = cell(1);
                cntf = 0;
                for nel = 1:size(dd,1)
                    N = dd(nel).name;
                    Nf = dd(nel).folder;
                    if dd(nel).isdir && N(1)~='.'
                        ddd = dir([Nf sl N]);
                        idd = zeros(length(ddd),1);
                        for n = 1:length(ddd)
                            Nd = ddd(n).name;
                            if contains(Nd,'.tif') || contains(Nd,'.jpg') || contains(Nd,'.png')
                                idd(n) = 1;
                            end
                        end
                        if sum(idd)>0
                            disp([Nf sl N])
                            cntf = cntf+1;
                            Folders{cntf} = [Nf sl N];
                        end
                    end
                end
                %%%%%%
                if app.BatchmodeCheckBox.Value
                    folder = [Folders{1} sl];
                    d = dir(folder);
                end


                id = zeros(length(d),1);
                for n = 1:length(d)
                    N = d(n).name;
                    if (contains(N,'.tif') || contains(N,'.jpg') || contains(N,'.png')) && N(1)~='.'
                        id(n) = 1;

                    end
                end
                keyIndex = 1:length(d);
                rawImages = cell(sum(id),1);
                selectName = cell(sum(id),1);
                imageType = cell(sum(id),1);
                fileName = cell(sum(id),1);
                folderN = cell(sum(id),1);
                pixelsize = cell(sum(id),1);

                app.AutomaticfeaturerecognitionButton.UserData = keyIndex;
                if strcmp(app.AutomaticfeaturerecognitionButton.Text,...
                        "Automatic feature recognition")
                    load('NetLinesContacts.mat','net')
                end
                cntl = 0;
                cntc = 0;
                cnt = 0;
                for n = 1:length(d)
                    N = d(n).name;
                    if id(n) == 1
                        cnt = cnt+1;
                        %                         %%%%
                        %                         if strcmp(app.SEMmodelDropDown.Value,'Zeiss')
                        %                             %Zeiss SEM: reading image pixel size from header
                        %                             A = imfinfo([folder N]);
                        %                             v = A.UnknownTags.Value;
                        %                             r = strfind(v,char(13));
                        %                             for nn = 1:(length(r)-1)
                        %                                 s = v(r(nn)+1:r(nn+1)-1);
                        %                                 if contains(s,'Image Pixel Size = ')
                        %                                     ids1 = strfind(s,'Image Pixel Size = ');
                        %                                     s = s(ids1(1)+19:end);
                        %                                     ids = strfind(s,' ');
                        %                                     sc = s(1:ids(1)-1);
                        %                                     unit = s(ids(1)+1:end);
                        %                                     switch unit
                        %                                         case 'um'
                        %                                             ps = str2double(sc)*1000;
                        %                                         case 'nm'
                        %                                             ps = str2double(sc);
                        %                                         case 'pm'
                        %                                             ps = str2double(sc)/1000;
                        %                                     end
                        %                                     pixelsize{cnt} = ps;
                        %                                     app.PixelsizenmEditField.Value = ps;
                        %                                 end
                        %                             end
                        %                         elseif strcmp(app.SEMmodelDropDown.Value,'Hitachi')
                        %                             %Hitachi SEM: reading image pixel size from header
                        %                             A = imfinfo([folder N]);
                        %                             v = A.UnknownTags.Value;
                        %                             vv = v(v~=0); %remove spaces
                        %                             r = find(vv==13); %detect line breaks
                        %                             ps = [];
                        %                             for nn = 1:(length(r)-1)
                        %                                 s = char(vv(r(nn)+1:r(nn+1)-1));
                        %                                 if contains(s,'PixelSize=')
                        %                                     ps = str2double(s(12:end));
                        %                                     pixelsize{cnt} = ps;
                        %                                     app.PixelsizenmEditField.Value = ps;
                        %                                 end
                        %                             end
                        %                         else
                        %                             pixelsize{cnt} = app.PixelsizenmEditField.Value;
                        %                         end
                        %
                        %                         %%%%
                        app.ImageLoadingProgress.Text = ['Loading ' N];
                        drawnow
                        rima = imread([folder N]);
                        if ndims(rima) == 3
                            rima = mean(double(rima),3,'omitnan');
                        end
                        rawImages{cnt} = rima;
                        if strcmp(app.AutomaticfeaturerecognitionButton.Text,...
                                "Automatic feature recognition")
                            imageType{cnt} = char(classify(net,imresize(rawImages{cnt},net.Layers(1).InputSize(1:2))));
                        else
                            imageType{cnt} = 'Lines';
                        end
                        fileName{cnt} = N;
                        folderN{cnt} = folder;
                        selectName{cnt} = true;


                    end
                end

                %Split indices for lines and contacts
                idl = false(cnt,1);
                idc = false(cnt,1);
                for n = 1:cnt
                    if strcmp(imageType{n},'Lines')
                        idl(n) = true;
                        cntl = cntl+1;
                    elseif strcmp(imageType{n},'Contacts')
                        idc(n) = true;
                        cntc = cntc+1;
                    end
                end
                Sl = size(Fields,1);
                tableData = cell(cntl,Sl);
                tableData(:,1) = selectName(idl);
                tableData(:,2) = fileName(idl);
                tableData(:,3) = imageType(idl);

                Sc = size(Fields_C,1);
                tableData_C = cell(cntc,Sc);
                tableData_C(:,1) = selectName(idc);
                tableData_C(:,2) = fileName(idc);
                tableData_C(:,3) = imageType(idc);


                app.dataTable.Data = tableData;
                app.dataTable_Contacts.Data = tableData_C;

                AllDataTable = app.dataTable.UserData;
                if isempty(AllDataTable)
                    AllDataTable=struct();
                end
                app.dataTable.UserData = AllDataTable;

                dataStructure = app.ExportdataButton.UserData;

                dataStructure.('fileName') = fileName;
                dataStructure.('folder') = folder;
                dataStructure.('folders') = Folders;
                dataStructure.('Select') = selectName;
                dataStructure.('Type') = imageType;
                dataStructure.('rawImages') = rawImages;
                dataStructure.('rawImagesAdjusted') = cell(size(rawImages));
                dataStructure.('ImagesRC') = cell(size(rawImages));
                dataStructure.('CentersB') = cell(size(rawImages));
                dataStructure.('CentersP') = cell(size(rawImages));
                dataStructure.('idc')=idc;
                dataStructure.('idl')=idl;
                dataStructure.('PixelSize') = pixelsize;
                app.ExportdataButton.UserData = dataStructure;


                vs = zeros(size(selectName));
                for n = 1:size(selectName,1)
                    vs(n) = selectName{n};
                end
                id = find(vs,1);
                app.ProcessimagesButton.UserData = id;
                imagesc(app.Image,rawImages{id});
                axis(app.Image,'image');colormap(app.Image,gray)
                title(app.Image,dataStructure.fileName{id},'interpreter','none')
                imagesc(app.ImageParameters,rawImages{id});
                axis(app.ImageParameters,'image');colormap(app.ImageParameters,gray)
                title(app.ImageParameters,dataStructure.fileName{id},'interpreter','none')
                app.AnalysisprogressGauge.UserData = 1;
            end
            app.ImageLoadingProgress.Text='';

        end

        % Button pushed function: ProcessimagesButton
        function ProcessimagesButtonPushed(app, event)

            %%%%
            dataStructure = app.ExportdataButton.UserData;
            if app.BatchmodeCheckBox.Value

                Folders = dataStructure.folders;

                for nfol = 1:numel(Folders)
                    folder = Folders{nfol};
                    d = dir(folder);


                    id = zeros(length(d),1);
                    for n = 1:length(d)
                        N = d(n).name;
                        if (contains(N,'.tif') || contains(N,'.jpg')) && N(1)~='.'
                            id(n) = 1;
                        end
                    end
                    keyIndex = 1:length(d);
                    rawImages = cell(sum(id),1);
                    selectName = cell(sum(id),1);
                    imageType = cell(sum(id),1);
                    fileName = cell(sum(id),1);
                    folderN = cell(sum(id),1);
                    pixelsize = cell(sum(id),1);

                    app.AutomaticfeaturerecognitionButton.UserData = keyIndex;
                    if strcmp(app.AutomaticfeaturerecognitionButton.Text,...
                            "Automatic feature recognition")
                        load('NetLinesContacts.mat','net')
                    end
                    cntl = 0;
                    cntc = 0;
                    cnt = 0;
                    for n = 1:length(d)
                        N = d(n).name;
                        if id(n) == 1
                            cnt = cnt+1;
                            app.ImageLoadingProgress.Text = ['Loading ' N];
                            drawnow
                            if ispc
                                sl = '\';
                            else
                                sl = '/';
                            end
                            rima = imread([folder sl N]);
                            if ndims(rima) == 3
                                rima = mean(double(rima),3,'omitnan');
                            end
                            rawImages{cnt} = rima;
                            if strcmp(app.AutomaticfeaturerecognitionButton.Text,...
                                    "Automatic feature recognition")
                                imageType{cnt} = char(classify(net,imresize(rawImages{cnt},net.Layers(1).InputSize(1:2))));
                            else
                                imageType{cnt} = 'Lines';
                            end
                            fileName{cnt} = N;
                            folderN{cnt} = folder;
                            selectName{cnt} = true;


                        end
                    end

                    %Split indices for lines and contacts
                    idl = false(cnt,1);
                    idc = false(cnt,1);
                    for n = 1:cnt
                        if strcmp(imageType{n},'Lines')
                            idl(n) = true;
                            cntl = cntl+1;
                        elseif strcmp(imageType{n},'Contacts')
                            idc(n) = true;
                            cntc = cntc+1;
                        end
                    end
                    [Fields,Fields_C] = FieldNames(app);

                    Sl = size(Fields,1);
                    tableData = cell(cntl,Sl);
                    tableData(:,1) = selectName(idl);
                    tableData(:,2) = fileName(idl);
                    tableData(:,3) = imageType(idl);

                    Sc = size(Fields_C,1);
                    tableData_C = cell(cntc,Sc);
                    tableData_C(:,1) = selectName(idc);
                    tableData_C(:,2) = fileName(idc);
                    tableData_C(:,3) = imageType(idc);


                    app.dataTable.Data = tableData;
                    app.dataTable_Contacts.Data = tableData_C;

                    AllDataTable = app.dataTable.UserData;
                    if isempty(AllDataTable)
                        AllDataTable=struct();
                    end
                    app.dataTable.UserData = AllDataTable;

                    dataStructure = app.ExportdataButton.UserData;

                    dataStructure.('fileName') = fileName;
                    dataStructure.('folder') = folderN;
                    dataStructure.('Select') = selectName;
                    dataStructure.('Type') = imageType;
                    dataStructure.('rawImages') = rawImages;
                    dataStructure.('rawImagesAdjusted') = cell(size(rawImages));
                    dataStructure.('ImagesRC') = cell(size(rawImages));
                    dataStructure.('CentersB') = cell(size(rawImages));
                    dataStructure.('CentersP') = cell(size(rawImages));
                    dataStructure.('idc')=idc;
                    dataStructure.('idl')=idl;
                    dataStructure.('PixelSize') = pixelsize;
                    app.ExportdataButton.UserData = dataStructure;


                    vs = zeros(size(selectName));
                    for n = 1:size(selectName,1)
                        vs(n) = selectName{n};
                    end
                    id = find(vs,1);
                    app.ProcessimagesButton.UserData = id;
                    imagesc(app.Image,rawImages{id});
                    axis(app.Image,'image');colormap(app.Image,gray)
                    title(app.Image,dataStructure.fileName{id},'interpreter','none')
                    imagesc(app.ImageParameters,rawImages{id});
                    axis(app.ImageParameters,'image');colormap(app.ImageParameters,gray)
                    title(app.ImageParameters,dataStructure.fileName{id},'interpreter','none')
                    app.AnalysisprogressGauge.UserData = 1;

                    app.ImageLoadingProgress.Text='';


                    %%%%

                    % if iscell(dataStructure.folder)
                    %     Folders = dataStructure.folder(1);
                    %     dataStructure.folder = Folders;
                    % else
                    %     Folders = {dataStructure.folder};
                    % end


                    app.ProcessimagesButton.Text='Processing...';
                    app.ProcessimagesButton.BackgroundColor=[0.7,0.7,1];
                    TotalCount = 0;
                    if ~isempty(app.dataTable_Contacts.Data)
                        DLC = app.dataTable_Contacts.Data(:,1);
                        for n = 1:numel(DLC)
                            TotalCount = TotalCount+DLC{n};
                        end
                    end
                    if ~isempty(app.dataTable.Data)
                        DLS = app.dataTable.Data(:,1);
                        for n = 1:numel(DLS)
                            if islogical(DLS{n})
                                TotalCount = TotalCount+DLS{n};
                            end
                        end
                    end
                    app.AnalysisprogressGauge.Limits = [0 TotalCount];
                    app.AnalysisprogressGauge.Value = 0;

                    app.StopButton.Visible = false(1);
                    app.PauseButton.Visible = true(1);
                    %Gather the parameters from the GUI
                    parameters = gatherParameters(app);


                    %Initialize output structure
                    B = struct();
                    B.isAverage = 'no';
                    B.leadingEdgeProfiles = [];
                    B.leadingEdgeProfilesFilled = [];
                    B.trailingEdgeProfiles = [];
                    B.trailingEdgeProfilesFilled = [];

                    B.LWvar = [];

                    D = app.dataTable.Data;
                    D_C = app.dataTable_Contacts.Data;

                    for sn = 1:length(dataStructure.Select)
                        idl = dataStructure.idl;
                        idc = dataStructure.idc;
                        if idl(sn)
                            dataStructure.Select{sn} = D{sum(idl(1:sn)),1};
                        elseif idc(sn)
                            dataStructure.Select{sn} = D_C{sum(idc(1:sn)),1};
                        end
                    end
                    for sn = 1:length(dataStructure.Select)
                        if strcmp(dataStructure.Type{sn},'Lines')
                            dataStructure.Select{sn} = D{sum(dataStructure.idl(1:sn)),1};
                        elseif strcmp(dataStructure.Type{sn},'Contacts')
                            dataStructure.Select{sn} = D_C{sum(dataStructure.idc(1:sn)),1};
                        end
                    end

                    S = dataStructure.Select;
                    T = dataStructure.Type;
                    ts = struct;
                    ts.EventName = '';

                    count = 0;

                    if isempty(app.ParametersTab.UserData)
                        AllParms = cell(size(S));
                    else
                        AllParms = app.ParametersTab.UserData;
                    end

                    ccnt = 0;
                    for n = 1:length(S)
                        ccnt = ccnt+S{n};
                    end
                    pixelsize = cell(ccnt,1);
                    pixelsizeAbs = cell(length(S),1);
                    for n = 1:length(S)
                        %h.lb_status.String = ['Processing image ' num2str(n) ' of ' num2str(length(S))];
                        drawnow
                        if S{n}
                            AllParms{n} = gatherParameters(app);
                            if isfield(dataStructure,'PixelSize')
                                AllParms{n}.ps = dataStructure.PixelSize{n};
                            end
                            count = count +1;

                            %app.PauseButton.UserData = n;
                            app.AnalysisprogressGauge.UserData = n;
                            ts.Indices = n;
                            f = dataStructure.folder{n};
                            fname = dataStructure.fileName{n};
                            parameters.filename = [f fname];
                            parameters.ps = dataStructure.PixelSize(n);
                            %parameters.rawImages = imread(parameters.filename);


                            %%%%%
                            if strcmp(app.SEMmodelDropDown.Value,'Zeiss')
                                %Zeiss SEM: reading image pixel size from header
                                A = imfinfo([f fname]);
                                v = A.UnknownTags.Value;
                                r = strfind(v,char(13));
                                for nn = 1:(length(r)-1)
                                    s = v(r(nn)+1:r(nn+1)-1);
                                    if contains(s,'Image Pixel Size = ')
                                        ids1 = strfind(s,'Image Pixel Size = ');
                                        s = s(ids1(1)+19:end);
                                        ids = strfind(s,' ');
                                        sc = s(1:ids(1)-1);
                                        unit = s(ids(1)+1:end);
                                        switch unit
                                            case 'um'
                                                ps = str2double(sc)*1000;
                                            case 'nm'
                                                ps = str2double(sc);
                                            case 'pm'
                                                ps = str2double(sc)/1000;
                                        end
                                        pixelsize{count} = ps;
                                        pixelsizeAbs{n} = ps;
                                        app.PixelsizenmEditField.Value = ps;
                                        dataStructure.PixelSize{count} = ps;
                                        dataStructure.PixelSizeAbs{n} = ps;
                                    end
                                end
                            elseif strcmp(app.SEMmodelDropDown.Value,'Hitachi')
                                %Hitachi SEM: reading image pixel size from header
                                A = imfinfo([f fname]);
                                v = A.UnknownTags.Value;
                                vv = v(v~=0); %remove spaces
                                r = find(vv==13); %detect line breaks

                                for nn = 1:(length(r)-1)
                                    s = char(vv(r(nn)+1:r(nn+1)-1));
                                    if contains(s,'PixelSize=')
                                        ps = str2double(s(12:end));
                                        pixelsize{count} = ps;
                                        pixelsizeAbs{n} = ps;
                                        app.PixelsizenmEditField.Value = ps;
                                        dataStructure.PixelSize{count} = ps;
                                        dataStructure.PixelSizeAbs{n} = ps;
                                    end
                                end
                            else
                                pixelsize{count} = app.PixelsizenmEditField.Value;
                                pixelsizeAbs{n} = app.PixelsizenmEditField.Value;
                                dataStructure.PixelSize{count} = app.PixelsizenmEditField.Value;
                                dataStructure.PixelSizeAbs{n} = app.PixelsizenmEditField.Value;
                            end


                            %%%%%
                            parameters.ps = dataStructure.PixelSize(count);


                            if isempty(dataStructure.rawImagesAdjusted{n})
                                parameters.rawImages = dataStructure.rawImages{n};
                            else
                                parameters.rawImages = dataStructure.rawImagesAdjusted{n};
                            end

                            if strcmp(T{n},'Contacts')

                                Output = SEM_Contact_Detect(parameters,app);

                                ps = dataStructure.PixelSize{n};
                                dataStructure.PS(n) = dataStructure.PixelSize{n};
                                dataStructure.ContactsContoursX{n} = Output.contacts_contoursX;
                                dataStructure.ContactsContoursY{n} = Output.contacts_contoursY;
                                dataStructure.ContactsContoursXs{n} = Output.contacts_contoursXs;
                                dataStructure.ContactsContoursYs{n} = Output.contacts_contoursYs;
                                dataStructure.ContactsCentersX{n} = Output.ContoursCentersX;
                                dataStructure.ContactsCentersY{n} = Output.ContoursCentersY;
                                dataStructure.Contacts_AverageRadius{n} = Output.contacts_radius;


                                app.ExportdataButton.UserData = dataStructure;
                                id = n;


                                Metrics = measureContacts(app,id);


                                dataStructure.CntMetrics{n} = Metrics;
                                dataStructure.ImagesRC{n} = Output.AC;
                                app.ExportdataButton.UserData = dataStructure;
                                nc = sum(dataStructure.idc(1:n));
                                D_C{nc,4} = numel(dataStructure.CntMetrics{n}.EllipseAngle);
                                D_C{nc,5} = 2*mean(dataStructure.CntMetrics{n}.Radii,'omitnan')*ps;
                                D_C{nc,6} = std(2*dataStructure.CntMetrics{n}.Radii,'omitnan')*ps;
                                D_C{nc,7} = mean(dataStructure.CntMetrics{n}.MajorSemiaxis,'omitnan')*ps;
                                D_C{nc,8} = std(dataStructure.CntMetrics{n}.MajorSemiaxis,'omitnan')*ps;
                                D_C{nc,9} = mean(dataStructure.CntMetrics{n}.MinorSemiaxis,'omitnan')*ps;
                                D_C{nc,10} = std(dataStructure.CntMetrics{n}.MinorSemiaxis,'omitnan')*ps;
                                D_C{nc,11} = mean(dataStructure.CntMetrics{n}.ellipticity,'omitnan');
                                D_C{nc,12} = std(dataStructure.CntMetrics{n}.ellipticity,'omitnan');
                                D_C{nc,13} = mean(dataStructure.CntMetrics{n}.EllipseAngle,'omitnan');
                                D_C{nc,14} = std(dataStructure.CntMetrics{n}.EllipseAngle,'omitnan');

                                app.dataTable_Contacts.Data = D_C;

                                displayData(app);

                            else

                                Output = SEM_line_Edge_detect(app,parameters);

                                B.PS = dataStructure.PixelSizeAbs{n};
                                ps = B.PS;
                                B.Arc = Output.Arc;

                                OLP = Output.leadingEdgeProfilesFilled;
                                OLT = Output.trailingEdgeProfilesFilled;

                                %Remove distortion
                                if app.AutorotationalignmentCheckBox.Value
                                    rald = 1;
                                else
                                    rald = 0;
                                end

                                OLPM = mean(OLP - mean(OLP),2);
                                OLTM = mean(OLT - mean(OLT),2);

                                smoothingFactor = app.APSmoothingEditField.Value;
                                OLP = OLP - rald*smooth(OLPM,smoothingFactor);
                                OLT = OLT - rald*smooth(OLTM,smoothingFactor);


                                LWvar = OLP-OLT;

                                B.leadingEdgeProfiles = Output.leadingEdgeProfiles;
                                B.trailingEdgeProfiles = Output.trailingEdgeProfiles;
                                B.leadingEdgeProfilesFilled = Output.leadingEdgeProfilesFilled;
                                B.trailingEdgeProfilesFilled = Output.trailingEdgeProfilesFilled;

                                B.leadingEdgeProfilesFilledCorrected = OLP;
                                B.trailingEdgeProfilesFilledCorrected = OLT;

                                B.LWvar = LWvar;
                                R = measureLines(B,app);


                                %%% Collecting the data for the current image
                                Pitch = Output.Pitch;
                                N_detected_features = size(LWvar,2);
                                Average_CD = R.mCD;
                                CDstd = R.stdCD;
                                AverageLWR_3s = R.LWR3s;
                                AverageLWR_PSD = R.LWR;
                                AverageLWR_PSD_unbiased = R.LWR_unbiased;
                                AverageLWR_PSD_fit_unbiased = R.LWR_fit_unbiased;
                                AverageLER_3s = R.LER3s;
                                AverageLER_PSD = R.LER;
                                AverageLER_PSD_unbiased = R.LER_unbiased;
                                AverageLER_PSD_fit_unbiased = R.LER_fit_unbiased;
                                AverageLER_le_3s = R.LERl3s;
                                AverageLER_le_PSD = R.LERl;
                                AverageLER_le_PSD_unbiased = R.LERl_unbiased;
                                AverageLER_le_PSD_fit_unbiased = R.LERl_fit_unbiased;
                                AverageLER_te_3s = R.LERt3s;
                                AverageLER_te_PSD = R.LERt;
                                AverageLER_te_PSD_unbiased = R.LERt_unbiased;
                                AverageLER_te_PSD_fit_unbiased = R.LERt_fit_unbiased;

                                %betaLWR = R.PSD_LWR_beta;
                                %betaLER = R.PSD_LER_beta;
                                %betaLERl = R.PSD_LERl_beta;
                                %betaLERt = R.PSD_LERt_beta;

                                HHCF_LWR = R.HHCorrFunc;
                                HHCF_LER = R.HHCorrFunc_LER;
                                HHCF_LERl = R.HHCorrFunc_LERl;
                                HHCF_LERt = R.HHCorrFunc_LERt;

                                HHCF_LWRfit = R.HHCorrFuncFit;
                                HHCF_LERfit = R.HHCorrFuncFit_LER;
                                HHCF_LERlfit = R.HHCorrFuncFit_LERl;
                                HHCF_LERtfit = R.HHCorrFuncFit_LERt;

                                LcLWR = R.LWRCorrLength;
                                LcLER = R.LERCorrLength;
                                LcLERl = R.LERlCorrLength;
                                LcLERt = R.LERtCorrLength;

                                nl = sum(dataStructure.idl(1:n));

                                D{nl,4} = N_detected_features;

                                D{nl,5} = Pitch;

                                D{nl,6} = real(Average_CD);
                                D{nl,7} = real(CDstd);
                                D{nl,8} = real(AverageLWR_3s);
                                D{nl,9} = real(AverageLWR_PSD);
                                D{nl,10} = real(AverageLWR_PSD_unbiased);
                                D{nl,11} = real(AverageLWR_PSD_fit_unbiased);
                                D{nl,12} = real(AverageLER_3s);
                                D{nl,13} = real(AverageLER_PSD);
                                D{nl,14} = real(AverageLER_PSD_unbiased);
                                D{nl,15} = real(AverageLER_PSD_fit_unbiased);
                                D{nl,16} = real(AverageLER_le_3s);
                                D{nl,17} = real(AverageLER_le_PSD);
                                D{nl,18} = real(AverageLER_le_PSD_unbiased);
                                D{nl,19} = real(AverageLER_le_PSD_fit_unbiased);
                                D{nl,20} = real(AverageLER_te_3s);
                                D{nl,21} = real(AverageLER_te_PSD);
                                D{nl,22} = real(AverageLER_te_PSD_unbiased);
                                D{nl,23} = real(AverageLER_te_PSD_fit_unbiased);

                                D{nl,24} = real(LcLWR);
                                D{nl,25} = real(LcLER);
                                D{nl,26} = real(LcLERl);
                                D{nl,27} = real(LcLERt);

                                %try
                                D{nl,28} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesBridged(:),'omitnan')+...
                                    sum(Output.trailingEdgeProfilesBridged(:),'omitnan')));
                                D{nl,29} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesPinched(:),'omitnan')+...
                                    sum(Output.trailingEdgeProfilesPinched(:),'omitnan')));
                                D{nl,30} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesSpikes(:),'omitnan')+...
                                    sum(Output.trailingEdgeProfilesSpikes(:),'omitnan')));
                                D{nl,31} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesUndetected(:),'omitnan')+...
                                    sum(Output.trailingEdgeProfilesUndetected(:),'omitnan')));
                                %catch
                                %    disp("Table issue")
                                %disp(size((Output.leadingEdgeProfilesBridged(:,nl))))
                                %disp(size((Output.trailingEdgeProfilesBridged(:,nl))))
                                %disp((Output.leadingEdgeProfilesSpikes(:,nl)))
                                %disp((Output.trailingEdgeProfilesSpikes(:,nl)))
                                %end
                                D{nl,32} = 0;
                                D{nl,33} = 0;
                                D{nl,34} = app.MultitaperButton.Value;
                                D{nl,35} = app.TaperfunctionsDropDown.Value;
                                D{nl,36} = app.NumberoftapersEditField.Value;

                                %%%
                                dataStructure.CentersB{n} = Output.CentersB;
                                dataStructure.CentersP{n} = Output.CentersP;

                                dataStructure.ImagesRC{n} = Output.Arc;
                                dataStructure.ProfilesL{n} = Output.leadingEdgeProfiles;
                                dataStructure.ProfilesR{n} = Output.trailingEdgeProfiles;
                                %dataStructure.ProfilesLFilled{n} = Output.leadingEdgeProfilesFilled;
                                %dataStructure.ProfilesRFilled{n} = Output.trailingEdgeProfilesFilled;
                                dataStructure.ProfilesLFilled{n} = B.leadingEdgeProfilesFilledCorrected;
                                dataStructure.ProfilesRFilled{n} = B.trailingEdgeProfilesFilledCorrected;

                                dataStructure.LeProfilesBridged{n} = Output.leadingEdgeProfilesBridged;
                                dataStructure.TeProfilesBridged{n} = Output.trailingEdgeProfilesBridged;
                                dataStructure.LeProfilesPinched{n} = Output.leadingEdgeProfilesPinched;
                                dataStructure.TeProfilesPinched{n} = Output.trailingEdgeProfilesPinched;
                                dataStructure.LeProfilesSpikes{n} = Output.leadingEdgeProfilesSpikes;
                                dataStructure.TeProfilesSpikes{n} = Output.trailingEdgeProfilesSpikes;
                                dataStructure.LeProfilesUndetected{n} = Output.leadingEdgeProfilesUndetected;
                                dataStructure.TeProfilesUndetected{n} = Output.trailingEdgeProfilesUndetected;

                                %%%%
                                dataStructure.PS(n) = ps;
                                dataStructure.PSD_LWR{n} = R.PSD;
                                dataStructure.PSD_LWR_unbiased{n} = R.PSD_LWR_unbiased;
                                dataStructure.PSD_LER_unbiased{n} = R.PSD_LER_unbiased;
                                dataStructure.PSD_LERl_unbiased{n} = R.PSD_LERl_unbiased;
                                dataStructure.PSD_LERt_unbiased{n} = R.PSD_LERt_unbiased;
                                dataStructure.PSD_LWR_fit{n} = R.PSD_LWR_fit;
                                dataStructure.PSD_LWR_fit_unbiased{n} = R.PSD_LWR_fit_unbiased;
                                dataStructure.PSD_LER{n} = R.PSD_LER;
                                dataStructure.PSD_LER_fit{n} = R.PSD_LER_fit;
                                dataStructure.PSD_LER_fit_unbiased{n} = R.PSD_LER_fit_unbiased;
                                dataStructure.PSD_LERl{n} = R.PSD_LERl;
                                dataStructure.PSD_LERl_fit{n} = R.PSD_LERl_fit;
                                dataStructure.PSD_LERl_fit_unbiased{n} = R.PSD_LERl_fit_unbiased;
                                dataStructure.PSD_LERt{n} = R.PSD_LERt;
                                dataStructure.PSD_LERt_fit{n} = R.PSD_LERt_fit;
                                dataStructure.PSD_LERt_fit_unbiased{n} = R.PSD_LERt_fit_unbiased;
                                dataStructure.freq{n} = R.freq;

                                dataStructure.HHCorrF{n} = R.HHCorrFunc;
                                dataStructure.HHCorrFLER{n} = R.HHCorrFunc_LER;
                                dataStructure.HHCorrFLERl{n} = R.HHCorrFunc_LERl;
                                dataStructure.HHCorrFLERt{n} = R.HHCorrFunc_LERt;

                                dataStructure.HHCorrFfit{n} = R.HHCorrFuncFit;
                                dataStructure.HHCorrFLERfit{n} = R.HHCorrFuncFit_LER;
                                dataStructure.HHCorrFLERlfit{n} = R.HHCorrFuncFit_LERl;
                                dataStructure.HHCorrFLERtfit{n} = R.HHCorrFuncFit_LERt;

                                dataStructure.LcLWR{n} = LcLWR;
                                dataStructure.LcLER{n} = LcLER;
                                dataStructure.LcLERl{n} = LcLERl;
                                dataStructure.LcLERt{n} = LcLERt;


                                dataStructure.HHCorrFFit{n} = R.HHCorrFuncFit;
                                dataStructure.LWRCorrLength{n} = R.LWRCorrLength;


                                dataStructure.r{n} = R.r;
                                dataStructure.LinesCD{n} = R.LinesCD;
                                dataStructure.LinesCenters{n} = R.LinesCenters;

                                dataStructure.Pitch{n} = Pitch;

                                app.ExportdataButton.UserData = dataStructure;


                                FN = fieldnames(parameters);
                                cntp = 0;

                                displayData(app)
                                app.dataTable.Data = D;

                                R2_dataTable(app,R,n)

                                %                         m = mean(R.PSD(2:end));
                                %                         R2 = 1-sum((R.PSD(2:end)-R.PSD_LWR_fit(2:end)).^2)/sum((R.PSD(2:end)-m).^2);
                                %                         disp(R2)
                                %                         s1 = uistyle("BackgroundColor",[1-R2,0.1,0.1]);
                                %                         addStyle(app.dataTable,s1,"cell",[n,3])
                                drawnow

                            end
                        end
                        app.AnalysisprogressGauge.Value = count;


                        if strcmp(app.StopButton.UserData,'stop')
                            app.StopButton.UserData = 'go';
                            break
                        end
                    end

                    app.ProcessimagesButton.Text='Process images';
                    app.ProcessimagesButton.BackgroundColor=[0.96,0.96,0.96];

                    app.PauseButton.Visible = 'off';
                    app.StopButton.Visible = 'off';

                    app.ParametersTab.UserData = AllParms;
                    app.ExportdataButton.UserData = dataStructure;
                    %h.lb_status.String = ['Processing image ' num2str(n) ' of ' num2str(length(S))];
                    %Now the average (if there are any lines)


                    B = struct();
                    B.isAverage = 'yes';

                    %             llength = 0;
                    %             tlength = 0;
                    %             for k = 1:numel(S)
                    %                 if S{k}
                    %                     llength = llength+size(dataStructure.ProfilesLFilled{k},2);
                    %                     tlength = tlength+size(dataStructure.ProfilesRFilled{k},2);
                    %                 end
                    %             end

                    L = [];
                    T = [];

                    NLineImages = 0;
                    NLineImagesSelected = 0;
                    for n = 1:numel(S)
                        if strcmp(dataStructure.Type{n},'Lines')
                            NLineImages = NLineImages+1;
                            if S{n}
                                NLineImagesSelected = NLineImagesSelected+1;
                            end
                        end
                    end
                    PS = [];
                    for n = 1:numel(S)
                        if S{n} && strcmp(dataStructure.Type{n},'Lines')
                            a = dataStructure.ProfilesLFilled{n};
                            b = dataStructure.ProfilesRFilled{n};
                            L = [L a];
                            T = [T b];
                            PS = [PS ones(1,size(a,2))*dataStructure.PixelSizeAbs{n}];
                        end
                    end

                    if ~isempty(L)
                        B.PS = PS;
                        B.leadingEdgeProfilesFilledCorrected = L;
                        B.trailingEdgeProfilesFilledCorrected = T;
                        B.LWvar = L-T;
                        R = measureLines(B,app);
                        %R.mCD
                        D{NLineImages+1,3} = 'Average';
                        pitches = zeros(NLineImages,1);
                        pitchmean = 0;
                        pitchcnt = 0;
                        allCD = [];
                        for nli = 1:NLineImages
                            if S{nli}
                                pitches(nli) = D{nli,5};
                                pitchcnt = pitchcnt + 1;
                                pitchmean = pitchmean + D{nli,5};
                                allCD = [allCD dataStructure.LinesCD{nli}];
                            end
                        end
                        D{NLineImages+1,4} = size(L,2);
                        D{NLineImages+1,5} = pitchmean/pitchcnt;
                        D{NLineImages+1,6} = mean(allCD)
                        D{NLineImages+1,7} = std(allCD);
                        D{NLineImages+1,8} = real(R.LWR3s);
                        D{NLineImages+1,9} = real(R.LWR);
                        D{NLineImages+1,10} = real(R.LWR_unbiased);
                        D{NLineImages+1,11} = real(R.LWR_fit_unbiased);
                        D{NLineImages+1,12} = real(R.LER3s);
                        D{NLineImages+1,13} = real(R.LER);
                        D{NLineImages+1,14} = real(R.LER_unbiased);
                        D{NLineImages+1,15} = real(R.LER_fit_unbiased);
                        D{NLineImages+1,16} = real(R.LERl3s);
                        D{NLineImages+1,17} = real(R.LERl);
                        D{NLineImages+1,18} = real(R.LERl_unbiased);
                        D{NLineImages+1,19} = real(R.LERl_fit_unbiased);
                        D{NLineImages+1,20} = real(R.LERt3s);
                        D{NLineImages+1,21} = real(R.LERt);
                        D{NLineImages+1,22} = real(R.LERt_unbiased);
                        D{NLineImages+1,23} = real(R.LERt_fit_unbiased);

                        D{NLineImages+1,24} = real(R.LWRCorrLength);
                        D{NLineImages+1,25} = real(R.LERCorrLength);
                        D{NLineImages+1,26} = real(R.LERlCorrLength);
                        D{NLineImages+1,27} = real(R.LERtCorrLength);

                        app.dataTable.Data = D;
                        R2_dataTable(app,R,NLineImages+1);

                        %%%% Add average items in the last position in the data
                        %%%% structure
                        dataStructure.PSD_LWR_a = R.PSD;
                        dataStructure.PSD_LWR_unbiased_a = R.PSD_LWR_unbiased;
                        dataStructure.PSD_LER_unbiased_a = R.PSD_LER_unbiased;
                        dataStructure.PSD_LERl_unbiased_a = R.PSD_LERl_unbiased;
                        dataStructure.PSD_LERt_unbiased_a = R.PSD_LERt_unbiased;
                        dataStructure.PSD_LWR_fit_a = R.PSD_LWR_fit;
                        dataStructure.PSD_LWR_fit_unbiased_a = R.PSD_LWR_fit_unbiased;
                        dataStructure.PSD_LER_a = R.PSD_LER;
                        dataStructure.PSD_LER_fit_a = R.PSD_LER_fit;
                        dataStructure.PSD_LER_fit_unbiased_a = R.PSD_LER_fit_unbiased;
                        dataStructure.PSD_LERl_a = R.PSD_LERl;
                        dataStructure.PSD_LERl_fit_a = R.PSD_LERl_fit;
                        dataStructure.PSD_LERl_fit_unbiased_a = R.PSD_LERl_fit_unbiased;
                        dataStructure.PSD_LERt_a = R.PSD_LERt;
                        dataStructure.PSD_LERt_fit_a = R.PSD_LERt_fit;
                        dataStructure.PSD_LERt_fit_unbiased_a = R.PSD_LERt_fit_unbiased;
                        dataStructure.freq_a = R.freq;

                        dataStructure.HHCorrF_a = R.HHCorrFunc;
                        dataStructure.HHCorrFLER_a = R.HHCorrFunc_LER;
                        dataStructure.HHCorrFLERl_a = R.HHCorrFunc_LERl;
                        dataStructure.HHCorrFLERt_a = R.HHCorrFunc_LERt;

                        dataStructure.HHCorrFfit_a = R.HHCorrFuncFit;
                        dataStructure.HHCorrFLERfit_a = R.HHCorrFuncFit_LER;
                        dataStructure.HHCorrFLERlfit_a = R.HHCorrFuncFit_LERl;
                        dataStructure.HHCorrFLERtfit_a = R.HHCorrFuncFit_LERt;

                        dataStructure.LcLWR_a = LcLWR;
                        dataStructure.LcLER_a = LcLER;
                        dataStructure.LcLERl_a = LcLERl;
                        dataStructure.LcLERt_a = LcLERt;


                        dataStructure.HHCorrFFit_a = R.HHCorrFuncFit;
                        dataStructure.LWRCorrLength_a = R.LWRCorrLength;


                        dataStructure.r_a = R.r;
                        dataStructure.LinesCD_a = R.LinesCD;
                        dataStructure.LinesCenters_a = R.LinesCenters;

                        dataStructure.Pitch_a = Pitch;

                        app.ExportdataButton.UserData = dataStructure;
                        %%%%%


                        app.averageCheckBox.UserData = R;
                    end
                    %%%
                    exportData(app)
                    %%%
                end
            else
                app.ProcessimagesButton.Text='Processing...';
                app.ProcessimagesButton.BackgroundColor=[0.7,0.7,1];
                TotalCount = 0;
                if ~isempty(app.dataTable_Contacts.Data)
                    DLC = app.dataTable_Contacts.Data(:,1);
                    for n = 1:numel(DLC)
                        TotalCount = TotalCount+DLC{n};
                    end
                end
                if ~isempty(app.dataTable.Data)
                    DLS = app.dataTable.Data(:,1);
                    for n = 1:numel(DLS)
                        if islogical(DLS{n})
                            TotalCount = TotalCount+DLS{n};
                        end
                    end
                end
                app.AnalysisprogressGauge.Limits = [0 TotalCount];
                app.AnalysisprogressGauge.Value = 0;

                app.StopButton.Visible = false(1);
                app.PauseButton.Visible = true(1);
                %Gather the parameters from the GUI
                parameters = gatherParameters(app);


                %Initialize output structure
                B = struct();
                B.isAverage = 'no';
                B.leadingEdgeProfiles = [];
                B.leadingEdgeProfilesFilled = [];
                B.trailingEdgeProfiles = [];
                B.trailingEdgeProfilesFilled = [];

                B.LWvar = [];

                D = app.dataTable.Data;
                D_C = app.dataTable_Contacts.Data;

                for sn = 1:length(dataStructure.Select)
                    idl = dataStructure.idl;
                    idc = dataStructure.idc;
                    if idl(sn)
                        dataStructure.Select{sn} = D{sum(idl(1:sn)),1};
                    elseif idc(sn)
                        dataStructure.Select{sn} = D_C{sum(idc(1:sn)),1};
                    end
                end
                for sn = 1:length(dataStructure.Select)
                    if strcmp(dataStructure.Type{sn},'Lines')
                        dataStructure.Select{sn} = D{sum(dataStructure.idl(1:sn)),1};
                    elseif strcmp(dataStructure.Type{sn},'Contacts')
                        dataStructure.Select{sn} = D_C{sum(dataStructure.idc(1:sn)),1};
                    end
                end

                S = dataStructure.Select;
                T = dataStructure.Type;
                ts = struct;
                ts.EventName = '';

                count = 0;

                if isempty(app.ParametersTab.UserData)
                    AllParms = cell(size(S));
                else
                    AllParms = app.ParametersTab.UserData;
                end

                ccnt = 0;
                for n = 1:length(S)
                    ccnt = ccnt+S{n};
                end
                pixelsize = cell(ccnt,1);
                pixelsizeAbs = cell(length(S),1);
                for n = 1:length(S)
                    %h.lb_status.String = ['Processing image ' num2str(n) ' of ' num2str(length(S))];
                    drawnow
                    if S{n}
                        AllParms{n} = gatherParameters(app);
                        if isfield(dataStructure,'PixelSize')
                            AllParms{n}.ps = dataStructure.PixelSize{n};
                        end
                        count = count +1;

                        %app.PauseButton.UserData = n;
                        app.AnalysisprogressGauge.UserData = n;
                        ts.Indices = n;
                        f = dataStructure.folder;
                        fname = dataStructure.fileName{n};
                        parameters.filename = [f fname];
                        parameters.ps = dataStructure.PixelSize(n);
                        %parameters.rawImages = imread(parameters.filename);


                        %%%%%
                        if strcmp(app.SEMmodelDropDown.Value,'Zeiss')
                            %Zeiss SEM: reading image pixel size from header
                            A = imfinfo([f fname]);
                            v = A.UnknownTags.Value;
                            r = strfind(v,char(13));
                            for nn = 1:(length(r)-1)
                                s = v(r(nn)+1:r(nn+1)-1);
                                if contains(s,'Image Pixel Size = ')
                                    ids1 = strfind(s,'Image Pixel Size = ');
                                    s = s(ids1(1)+19:end);
                                    ids = strfind(s,' ');
                                    sc = s(1:ids(1)-1);
                                    unit = s(ids(1)+1:end);
                                    switch unit
                                        case 'um'
                                            ps = str2double(sc)*1000;
                                        case 'nm'
                                            ps = str2double(sc);
                                        case 'pm'
                                            ps = str2double(sc)/1000;
                                    end
                                    pixelsize{count} = ps;
                                    pixelsizeAbs{n} = ps;
                                    app.PixelsizenmEditField.Value = ps;
                                    dataStructure.PixelSize{count} = ps;
                                    dataStructure.PixelSizeAbs{n} = ps;
                                end
                            end
                        elseif strcmp(app.SEMmodelDropDown.Value,'Hitachi')
                            %Hitachi SEM: reading image pixel size from header
                            A = imfinfo([f fname]);
                            v = A.UnknownTags.Value;
                            vv = v(v~=0); %remove spaces
                            r = find(vv==13); %detect line breaks

                            for nn = 1:(length(r)-1)
                                s = char(vv(r(nn)+1:r(nn+1)-1));
                                if contains(s,'PixelSize=')
                                    ps = str2double(s(12:end));
                                    pixelsize{count} = ps;
                                    pixelsizeAbs{n} = ps;
                                    app.PixelsizenmEditField.Value = ps;
                                    dataStructure.PixelSize{count} = ps;
                                    dataStructure.PixelSizeAbs{n} = ps;
                                end
                            end
                        else
                            pixelsize{count} = app.PixelsizenmEditField.Value;
                            pixelsizeAbs{n} = app.PixelsizenmEditField.Value;
                            dataStructure.PixelSize{count} = app.PixelsizenmEditField.Value;
                            dataStructure.PixelSizeAbs{n} = app.PixelsizenmEditField.Value;
                        end


                        %%%%%
                        parameters.ps = dataStructure.PixelSize(count);


                        if isempty(dataStructure.rawImagesAdjusted{n})
                            parameters.rawImages = dataStructure.rawImages{n};
                        else
                            parameters.rawImages = dataStructure.rawImagesAdjusted{n};
                        end

                        if strcmp(T{n},'Contacts')

                            Output = SEM_Contact_Detect(parameters,app);

                            ps = dataStructure.PixelSize{n};
                            dataStructure.PS(n) = dataStructure.PixelSize{n};
                            dataStructure.ContactsContoursX{n} = Output.contacts_contoursX;
                            dataStructure.ContactsContoursY{n} = Output.contacts_contoursY;
                            dataStructure.ContactsContoursXs{n} = Output.contacts_contoursXs;
                            dataStructure.ContactsContoursYs{n} = Output.contacts_contoursYs;
                            dataStructure.ContactsCentersX{n} = Output.ContoursCentersX;
                            dataStructure.ContactsCentersY{n} = Output.ContoursCentersY;
                            dataStructure.Contacts_AverageRadius{n} = Output.contacts_radius;


                            app.ExportdataButton.UserData = dataStructure;
                            id = n;


                            Metrics = measureContacts(app,id);


                            dataStructure.CntMetrics{n} = Metrics;
                            dataStructure.ImagesRC{n} = Output.AC;
                            app.ExportdataButton.UserData = dataStructure;
                            nc = sum(dataStructure.idc(1:n));
                            D_C{nc,4} = numel(dataStructure.CntMetrics{n}.EllipseAngle);
                            D_C{nc,5} = 2*mean(dataStructure.CntMetrics{n}.Radii,'omitnan')*ps;
                            D_C{nc,6} = std(2*dataStructure.CntMetrics{n}.Radii,'omitnan')*ps;
                            D_C{nc,7} = mean(dataStructure.CntMetrics{n}.MajorSemiaxis,'omitnan')*ps;
                            D_C{nc,8} = std(dataStructure.CntMetrics{n}.MajorSemiaxis,'omitnan')*ps;
                            D_C{nc,9} = mean(dataStructure.CntMetrics{n}.MinorSemiaxis,'omitnan')*ps;
                            D_C{nc,10} = std(dataStructure.CntMetrics{n}.MinorSemiaxis,'omitnan')*ps;
                            D_C{nc,11} = mean(dataStructure.CntMetrics{n}.ellipticity,'omitnan');
                            D_C{nc,12} = std(dataStructure.CntMetrics{n}.ellipticity,'omitnan');
                            D_C{nc,13} = mean(dataStructure.CntMetrics{n}.EllipseAngle,'omitnan');
                            D_C{nc,14} = std(dataStructure.CntMetrics{n}.EllipseAngle,'omitnan');

                            app.dataTable_Contacts.Data = D_C;

                            displayData(app);

                        else

                            Output = SEM_line_Edge_detect(app,parameters);

                            B.PS = dataStructure.PixelSizeAbs{n};
                            ps = B.PS;
                            B.Arc = Output.Arc;

                            OLP = Output.leadingEdgeProfilesFilled;
                            OLT = Output.trailingEdgeProfilesFilled;

                            %Remove distortion
                            if app.AutorotationalignmentCheckBox.Value
                                rald = 1;
                            else
                                rald = 0;
                            end

                            OLPM = mean(OLP - mean(OLP),2);
                            OLTM = mean(OLT - mean(OLT),2);

                            smoothingFactor = app.APSmoothingEditField.Value;
                            OLP = OLP - rald*smooth(OLPM,smoothingFactor);
                            OLT = OLT - rald*smooth(OLTM,smoothingFactor);


                            LWvar = OLP-OLT;

                            B.leadingEdgeProfiles = Output.leadingEdgeProfiles;
                            B.trailingEdgeProfiles = Output.trailingEdgeProfiles;
                            B.leadingEdgeProfilesFilled = Output.leadingEdgeProfilesFilled;
                            B.trailingEdgeProfilesFilled = Output.trailingEdgeProfilesFilled;

                            B.leadingEdgeProfilesFilledCorrected = OLP;
                            B.trailingEdgeProfilesFilledCorrected = OLT;

                            B.LWvar = LWvar;
                            R = measureLines(B,app);


                            %%% Collecting the data for the current image
                            Pitch = Output.Pitch;
                            N_detected_features = size(LWvar,2);
                            Average_CD = R.mCD;
                            CDstd = R.stdCD;
                            AverageLWR_3s = R.LWR3s;
                            AverageLWR_PSD = R.LWR;
                            AverageLWR_PSD_unbiased = R.LWR_unbiased;
                            AverageLWR_PSD_fit_unbiased = R.LWR_fit_unbiased;
                            AverageLER_3s = R.LER3s;
                            AverageLER_PSD = R.LER;
                            AverageLER_PSD_unbiased = R.LER_unbiased;
                            AverageLER_PSD_fit_unbiased = R.LER_fit_unbiased;
                            AverageLER_le_3s = R.LERl3s;
                            AverageLER_le_PSD = R.LERl;
                            AverageLER_le_PSD_unbiased = R.LERl_unbiased;
                            AverageLER_le_PSD_fit_unbiased = R.LERl_fit_unbiased;
                            AverageLER_te_3s = R.LERt3s;
                            AverageLER_te_PSD = R.LERt;
                            AverageLER_te_PSD_unbiased = R.LERt_unbiased;
                            AverageLER_te_PSD_fit_unbiased = R.LERt_fit_unbiased;

                            %betaLWR = R.PSD_LWR_beta;
                            %betaLER = R.PSD_LER_beta;
                            %betaLERl = R.PSD_LERl_beta;
                            %betaLERt = R.PSD_LERt_beta;

                            HHCF_LWR = R.HHCorrFunc;
                            HHCF_LER = R.HHCorrFunc_LER;
                            HHCF_LERl = R.HHCorrFunc_LERl;
                            HHCF_LERt = R.HHCorrFunc_LERt;

                            HHCF_LWRfit = R.HHCorrFuncFit;
                            HHCF_LERfit = R.HHCorrFuncFit_LER;
                            HHCF_LERlfit = R.HHCorrFuncFit_LERl;
                            HHCF_LERtfit = R.HHCorrFuncFit_LERt;

                            LcLWR = R.LWRCorrLength;
                            LcLER = R.LERCorrLength;
                            LcLERl = R.LERlCorrLength;
                            LcLERt = R.LERtCorrLength;

                            nl = sum(dataStructure.idl(1:n));

                            D{nl,4} = N_detected_features;

                            D{nl,5} = Pitch;

                            D{nl,6} = real(Average_CD);
                            D{nl,7} = real(CDstd);
                            D{nl,8} = real(AverageLWR_3s);
                            D{nl,9} = real(AverageLWR_PSD);
                            D{nl,10} = real(AverageLWR_PSD_unbiased);
                            D{nl,11} = real(AverageLWR_PSD_fit_unbiased);
                            D{nl,12} = real(AverageLER_3s);
                            D{nl,13} = real(AverageLER_PSD);
                            D{nl,14} = real(AverageLER_PSD_unbiased);
                            D{nl,15} = real(AverageLER_PSD_fit_unbiased);
                            D{nl,16} = real(AverageLER_le_3s);
                            D{nl,17} = real(AverageLER_le_PSD);
                            D{nl,18} = real(AverageLER_le_PSD_unbiased);
                            D{nl,19} = real(AverageLER_le_PSD_fit_unbiased);
                            D{nl,20} = real(AverageLER_te_3s);
                            D{nl,21} = real(AverageLER_te_PSD);
                            D{nl,22} = real(AverageLER_te_PSD_unbiased);
                            D{nl,23} = real(AverageLER_te_PSD_fit_unbiased);

                            D{nl,24} = real(LcLWR);
                            D{nl,25} = real(LcLER);
                            D{nl,26} = real(LcLERl);
                            D{nl,27} = real(LcLERt);

                            %try
                            D{nl,28} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesBridged(:),'omitnan')+...
                                sum(Output.trailingEdgeProfilesBridged(:),'omitnan')));
                            D{nl,29} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesPinched(:),'omitnan')+...
                                sum(Output.trailingEdgeProfilesPinched(:),'omitnan')));
                            D{nl,30} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesSpikes(:),'omitnan')+...
                                sum(Output.trailingEdgeProfilesSpikes(:),'omitnan')));
                            D{nl,31} = real(dataStructure.PixelSize{nl}*(sum(Output.leadingEdgeProfilesUndetected(:),'omitnan')+...
                                sum(Output.trailingEdgeProfilesUndetected(:),'omitnan')));
                            %catch
                            %    disp("Table issue")
                            %disp(size((Output.leadingEdgeProfilesBridged(:,nl))))
                            %disp(size((Output.trailingEdgeProfilesBridged(:,nl))))
                            %disp((Output.leadingEdgeProfilesSpikes(:,nl)))
                            %disp((Output.trailingEdgeProfilesSpikes(:,nl)))
                            %end
                            D{nl,32} = 0;
                            D{nl,33} = 0;
                            D{nl,34} = app.MultitaperButton.Value;
                            D{nl,35} = app.TaperfunctionsDropDown.Value;
                            D{nl,36} = app.NumberoftapersEditField.Value;

                            %%%
                            dataStructure.CentersB{n} = Output.CentersB;
                            dataStructure.CentersP{n} = Output.CentersP;

                            dataStructure.ImagesRC{n} = Output.Arc;
                            dataStructure.ProfilesL{n} = Output.leadingEdgeProfiles;
                            dataStructure.ProfilesR{n} = Output.trailingEdgeProfiles;
                            %dataStructure.ProfilesLFilled{n} = Output.leadingEdgeProfilesFilled;
                            %dataStructure.ProfilesRFilled{n} = Output.trailingEdgeProfilesFilled;
                            dataStructure.ProfilesLFilled{n} = B.leadingEdgeProfilesFilledCorrected;
                            dataStructure.ProfilesRFilled{n} = B.trailingEdgeProfilesFilledCorrected;

                            dataStructure.LeProfilesBridged{n} = Output.leadingEdgeProfilesBridged;
                            dataStructure.TeProfilesBridged{n} = Output.trailingEdgeProfilesBridged;
                            dataStructure.LeProfilesPinched{n} = Output.leadingEdgeProfilesPinched;
                            dataStructure.TeProfilesPinched{n} = Output.trailingEdgeProfilesPinched;
                            dataStructure.LeProfilesSpikes{n} = Output.leadingEdgeProfilesSpikes;
                            dataStructure.TeProfilesSpikes{n} = Output.trailingEdgeProfilesSpikes;
                            dataStructure.LeProfilesUndetected{n} = Output.leadingEdgeProfilesUndetected;
                            dataStructure.TeProfilesUndetected{n} = Output.trailingEdgeProfilesUndetected;

                            %%%%
                            dataStructure.PS(n) = ps;
                            dataStructure.PSD_LWR{n} = R.PSD;
                            dataStructure.PSD_LWR_unbiased{n} = R.PSD_LWR_unbiased;
                            dataStructure.PSD_LER_unbiased{n} = R.PSD_LER_unbiased;
                            dataStructure.PSD_LERl_unbiased{n} = R.PSD_LERl_unbiased;
                            dataStructure.PSD_LERt_unbiased{n} = R.PSD_LERt_unbiased;
                            dataStructure.PSD_LWR_fit{n} = R.PSD_LWR_fit;
                            dataStructure.PSD_LWR_fit_unbiased{n} = R.PSD_LWR_fit_unbiased;
                            dataStructure.PSD_LER{n} = R.PSD_LER;
                            dataStructure.PSD_LER_fit{n} = R.PSD_LER_fit;
                            dataStructure.PSD_LER_fit_unbiased{n} = R.PSD_LER_fit_unbiased;
                            dataStructure.PSD_LERl{n} = R.PSD_LERl;
                            dataStructure.PSD_LERl_fit{n} = R.PSD_LERl_fit;
                            dataStructure.PSD_LERl_fit_unbiased{n} = R.PSD_LERl_fit_unbiased;
                            dataStructure.PSD_LERt{n} = R.PSD_LERt;
                            dataStructure.PSD_LERt_fit{n} = R.PSD_LERt_fit;
                            dataStructure.PSD_LERt_fit_unbiased{n} = R.PSD_LERt_fit_unbiased;
                            dataStructure.freq{n} = R.freq;

                            dataStructure.HHCorrF{n} = R.HHCorrFunc;
                            dataStructure.HHCorrFLER{n} = R.HHCorrFunc_LER;
                            dataStructure.HHCorrFLERl{n} = R.HHCorrFunc_LERl;
                            dataStructure.HHCorrFLERt{n} = R.HHCorrFunc_LERt;

                            dataStructure.HHCorrFfit{n} = R.HHCorrFuncFit;
                            dataStructure.HHCorrFLERfit{n} = R.HHCorrFuncFit_LER;
                            dataStructure.HHCorrFLERlfit{n} = R.HHCorrFuncFit_LERl;
                            dataStructure.HHCorrFLERtfit{n} = R.HHCorrFuncFit_LERt;

                            dataStructure.LcLWR{n} = LcLWR;
                            dataStructure.LcLER{n} = LcLER;
                            dataStructure.LcLERl{n} = LcLERl;
                            dataStructure.LcLERt{n} = LcLERt;


                            dataStructure.HHCorrFFit{n} = R.HHCorrFuncFit;
                            dataStructure.LWRCorrLength{n} = R.LWRCorrLength;


                            dataStructure.r{n} = R.r;
                            dataStructure.LinesCD{n} = R.LinesCD;
                            dataStructure.LinesCenters{n} = R.LinesCenters;

                            dataStructure.Pitch{n} = Pitch;

                            app.ExportdataButton.UserData = dataStructure;


                            FN = fieldnames(parameters);
                            cntp = 0;

                            displayData(app)
                            app.dataTable.Data = D;

                            R2_dataTable(app,R,n)

                            %                         m = mean(R.PSD(2:end));
                            %                         R2 = 1-sum((R.PSD(2:end)-R.PSD_LWR_fit(2:end)).^2)/sum((R.PSD(2:end)-m).^2);
                            %                         disp(R2)
                            %                         s1 = uistyle("BackgroundColor",[1-R2,0.1,0.1]);
                            %                         addStyle(app.dataTable,s1,"cell",[n,3])
                            drawnow

                        end
                    end
                    app.AnalysisprogressGauge.Value = count;


                    if strcmp(app.StopButton.UserData,'stop')
                        app.StopButton.UserData = 'go';
                        break
                    end
                end

                app.ProcessimagesButton.Text='Process images';
                app.ProcessimagesButton.BackgroundColor=[0.96,0.96,0.96];

                app.PauseButton.Visible = 'off';
                app.StopButton.Visible = 'off';

                app.ParametersTab.UserData = AllParms;
                app.ExportdataButton.UserData = dataStructure;
                %h.lb_status.String = ['Processing image ' num2str(n) ' of ' num2str(length(S))];
                %Now the average (if there are any lines)


                B = struct();
                B.isAverage = 'yes';

                %             llength = 0;
                %             tlength = 0;
                %             for k = 1:numel(S)
                %                 if S{k}
                %                     llength = llength+size(dataStructure.ProfilesLFilled{k},2);
                %                     tlength = tlength+size(dataStructure.ProfilesRFilled{k},2);
                %                 end
                %             end

                L = [];
                T = [];

                NLineImages = 0;
                NLineImagesSelected = 0;
                for n = 1:numel(S)
                    if strcmp(dataStructure.Type{n},'Lines')
                        NLineImages = NLineImages+1;
                        if S{n}
                            NLineImagesSelected = NLineImagesSelected+1;
                        end
                    end
                end
                PS = [];
                for n = 1:numel(S)
                    if S{n} && strcmp(dataStructure.Type{n},'Lines')
                        a = dataStructure.ProfilesLFilled{n};
                        b = dataStructure.ProfilesRFilled{n};
                        L = [L a];
                        T = [T b];
                        PS = [PS ones(1,size(a,2))*dataStructure.PixelSizeAbs{n}];
                    end
                end

                if ~isempty(L)
                    B.PS = PS;
                    B.leadingEdgeProfilesFilledCorrected = L;
                    B.trailingEdgeProfilesFilledCorrected = T;
                    B.LWvar = L-T;
                    R = measureLines(B,app);
                    %R.mCD
                    D{NLineImages+1,3} = 'Average';
                    pitches = zeros(NLineImages,1);
                    pitchmean = 0;
                    allCD = [];
                    pitchcnt = 0;
                    for nli = 1:NLineImages
                        if S{nli}
                            pitches(nli) = D{nli,5};
                            pitchcnt = pitchcnt + 1;
                            pitchmean = pitchmean + D{nli,5};
                            allCD = [allCD dataStructure.LinesCD{nli}];
                        end
                    end
                    D{NLineImages+1,4} = size(L,2);
                    D{NLineImages+1,5} = pitchmean/pitchcnt;
                    D{NLineImages+1,6} = mean(allCD);
                    D{NLineImages+1,7} = std(allCD);
                    D{NLineImages+1,8} = real(R.LWR3s);
                    D{NLineImages+1,9} = real(R.LWR);
                    D{NLineImages+1,10} = real(R.LWR_unbiased);
                    D{NLineImages+1,11} = real(R.LWR_fit_unbiased);
                    D{NLineImages+1,12} = real(R.LER3s);
                    D{NLineImages+1,13} = real(R.LER);
                    D{NLineImages+1,14} = real(R.LER_unbiased);
                    D{NLineImages+1,15} = real(R.LER_fit_unbiased);
                    D{NLineImages+1,16} = real(R.LERl3s);
                    D{NLineImages+1,17} = real(R.LERl);
                    D{NLineImages+1,18} = real(R.LERl_unbiased);
                    D{NLineImages+1,19} = real(R.LERl_fit_unbiased);
                    D{NLineImages+1,20} = real(R.LERt3s);
                    D{NLineImages+1,21} = real(R.LERt);
                    D{NLineImages+1,22} = real(R.LERt_unbiased);
                    D{NLineImages+1,23} = real(R.LERt_fit_unbiased);

                    D{NLineImages+1,24} = real(R.LWRCorrLength);
                    D{NLineImages+1,25} = real(R.LERCorrLength);
                    D{NLineImages+1,26} = real(R.LERlCorrLength);
                    D{NLineImages+1,27} = real(R.LERtCorrLength);

                    app.dataTable.Data = D;
                    R2_dataTable(app,R,NLineImages+1);

                    %%%% Add average items in the last position in the data
                    %%%% structure
                    dataStructure.PSD_LWR_a = R.PSD;
                    dataStructure.PSD_LWR_unbiased_a = R.PSD_LWR_unbiased;
                    dataStructure.PSD_LER_unbiased_a = R.PSD_LER_unbiased;
                    dataStructure.PSD_LERl_unbiased_a = R.PSD_LERl_unbiased;
                    dataStructure.PSD_LERt_unbiased_a = R.PSD_LERt_unbiased;
                    dataStructure.PSD_LWR_fit_a = R.PSD_LWR_fit;
                    dataStructure.PSD_LWR_fit_unbiased_a = R.PSD_LWR_fit_unbiased;
                    dataStructure.PSD_LER_a = R.PSD_LER;
                    dataStructure.PSD_LER_fit_a = R.PSD_LER_fit;
                    dataStructure.PSD_LER_fit_unbiased_a = R.PSD_LER_fit_unbiased;
                    dataStructure.PSD_LERl_a = R.PSD_LERl;
                    dataStructure.PSD_LERl_fit_a = R.PSD_LERl_fit;
                    dataStructure.PSD_LERl_fit_unbiased_a = R.PSD_LERl_fit_unbiased;
                    dataStructure.PSD_LERt_a = R.PSD_LERt;
                    dataStructure.PSD_LERt_fit_a = R.PSD_LERt_fit;
                    dataStructure.PSD_LERt_fit_unbiased_a = R.PSD_LERt_fit_unbiased;
                    dataStructure.freq_a = R.freq;

                    dataStructure.HHCorrF_a = R.HHCorrFunc;
                    dataStructure.HHCorrFLER_a = R.HHCorrFunc_LER;
                    dataStructure.HHCorrFLERl_a = R.HHCorrFunc_LERl;
                    dataStructure.HHCorrFLERt_a = R.HHCorrFunc_LERt;

                    dataStructure.HHCorrFfit_a = R.HHCorrFuncFit;
                    dataStructure.HHCorrFLERfit_a = R.HHCorrFuncFit_LER;
                    dataStructure.HHCorrFLERlfit_a = R.HHCorrFuncFit_LERl;
                    dataStructure.HHCorrFLERtfit_a = R.HHCorrFuncFit_LERt;

                    dataStructure.LcLWR_a = LcLWR;
                    dataStructure.LcLER_a = LcLER;
                    dataStructure.LcLERl_a = LcLERl;
                    dataStructure.LcLERt_a = LcLERt;


                    dataStructure.HHCorrFFit_a = R.HHCorrFuncFit;
                    dataStructure.LWRCorrLength_a = R.LWRCorrLength;


                    dataStructure.r_a = R.r;
                    dataStructure.LinesCD_a = R.LinesCD;
                    dataStructure.LinesCenters_a = R.LinesCenters;

                    dataStructure.Pitch_a = Pitch;

                    app.ExportdataButton.UserData = dataStructure;
                    %%%%%


                    app.averageCheckBox.UserData = R;
                end
            end
        end

        % Button pushed function: SetROIButton
        function setROIButtonPushed(app, event)
            setROI(app)
        end

        % Selection changed function: DataDisplayButtonGroup, 
        % ...and 1 other component
        function DataDisplayButtonGroupSelectionChanged(app, event)
            %selectedButton = app.DataDisplayButtonGroup.SelectedObject;
            displayData(app)
        end

        % Value changed function: ShowerrorsButton
        function ShowerrorsButtonValueChanged(app, event)
            %value = app.ShowerrorsButton.Value;
            displayData(app)
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            %dataStructure = app.ExportdataButton.UserData;
            %S = dataStructure.Select;
            D = app.dataTable.Data;
            D_C = app.dataTable_Contacts.Data;
            if size(app.dataTable.Data,1)>0
                if strcmp(app.dataTable.Data{end,3},'Average')
                    L = size(D,1)-1;
                else
                    L = size(D,1);
                end
            else
                L = size(D,1);
            end
            for n = 1:L
                D{n,1} = true;
            end
            if size(app.dataTable_Contacts.Data,1)>0
                if strcmp(app.dataTable_Contacts.Data{end,3},'Average')
                    L = size(D_C,1)-1;
                else
                    L = size(D_C,1);
                end
            else
                L = size(D_C,1);
            end
            for n = 1:L
                D_C{n,1} = true;
            end
            app.dataTable.Data = D;
            app.dataTable_Contacts.Data = D_C;
        end

        % Button pushed function: UnselectallButton
        function UnselectallButtonPushed(app, event)
            %dataStructure = app.ExportdataButton.UserData;
            %S = dataStructure.Select;
            D = app.dataTable.Data;
            D_C = app.dataTable_Contacts.Data;
            if size(app.dataTable.Data,1)>0
                if strcmp(app.dataTable.Data{end,3},'Average')
                    L = size(D,1)-1;
                else
                    L = size(D,1);
                end
            else
                L = size(D,1);
            end
            for n = 1:L
                D{n,1} = false;
            end
            if size(app.dataTable_Contacts.Data,1)>0
                if strcmp(app.dataTable_Contacts.Data{end,3},'Average')
                    L = size(D_C,1)-1;
                else
                    L = size(D_C,1);
                end
            else
                L = size(D_C,1);
            end
            for n = 1:L
                D_C{n,1} = false;
            end
            app.dataTable.Data = D;
            app.dataTable_Contacts.Data = D_C;
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            parameters = gatherParameters(app);

            dataStructure = app.ExportdataButton.UserData;
            dataStructure.parameters = parameters;
            app.ExportdataButton.UserData = dataStructure;
            if ispc
                sl = '\';
            else
                sl = '/';
            end
            switch app.Deployment
                case 'none'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                    end

                    [filename, pathname] = uiputfile(['parameters' sl '*.mat']);
                case 'Win'
                    appdatafolder = [char(getenv('LocalAppData')) sl 'SMILE'];
                    if ~exist([appdatafolder sl 'parameters'],'dir')
                        mkdir([appdatafolder sl 'parameters'])
                    end

                    [filename, pathname] = uiputfile([appdatafolder sl 'parameters' sl '*.mat']);
            end
            if isequal(filename,0) || isequal(pathname,0)
                %Operation cancelled
            else
                save([pathname filename],'parameters')
            end

        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)

            if ispc
                sl = '\';
            else
                sl = '/';
            end


            switch app.Deployment
                case 'none'
                    if ~exist('parameters','dir')
                        mkdir('parameters')
                    end
                    [filename, pathname] = uigetfile(['parameters' sl ' *.mat']);
                case 'Win'
                    appdatafolder = [char(getenv('LocalAppData')) sl 'SMILE'];
                    if ~exist([appdatafolder sl 'parameters'],'dir')
                        mkdir([appdatafolder sl 'parameters'])
                    end
                    [filename, pathname] = uigetfile([appdatafolder sl 'parameters' sl ' *.mat']);
            end
            load([pathname filename],'parameters')

            %dataStructure = h.pb_export.UserData;

            app.maxYpixEditField.Value = parameters.ROI_Y2;
            app.minYpixEditField.Value = parameters.ROI_Y1;
            app.maxXpixEditField.Value = parameters.ROI_X2;
            app.minXpixEditField.Value = parameters.ROI_X1;
            app.AutorotationalignmentCheckBox.Value = parameters.rotation;
            app.RemovedistortionCheckBox.Value = parameters.RemoveDistortion;

            app.PixelsizenmEditField.Value = parameters.ps;
            app.ThresholdEditField.Value = parameters.threshold;
            app.MaxspikeEditField.Value = parameters.spikePeak;
            app.CDfractionEditField.Value = parameters.CD_fraction;
            app.EdgedetectionrangeEditField.Value = parameters.Edge_range;

            app.EdgerangeButton.Text = parameters.Edgerange;
            if strcmp(parameters.Edgerange,'Edge range')
                app.CDfractionEditField.Enable = 'on';
                app.EdgedetectionrangeEditField.Enable = 'off';
            else
                app.CDfractionEditField.Enable = 'off';
                app.EdgedetectionrangeEditField.Enable = 'on';
            end

            %app.parmFN.String = num2str(parameters.FN);

            %h.parmLN.String = num2str(parameters.LN);
            %h.ed_CF.String = num2str(parameters.CF);
            %h.parmExN.String = num2str(parameters.ExN);
            %h.parmExNe.String = num2str(parameters.ExNe);

            app.AlphaEditField.Value = parameters.Alpha;
            app.MaximumnumberofiterationsEditField.Value = parameters.MI;
            app.MaximumfunctionevaluationsEditField.Value = parameters.MFE;
            app.MinpeakprominenceEditField.Value = parameters.MinPeakProminence;
            app.MinpeakdistanceEditField.Value = parameters.MinPeakDistance;

            app.PSDmodelDropDown.Value = parameters.PSDModel;

            for n = 1:numel(app.EdgefitfunctionButtonGroup.Buttons)
                if strcmp(app.EdgefitfunctionButtonGroup.Buttons(n).Text,parameters.EdgeDetectionMethod)
                    app.EdgefitfunctionButtonGroup.Buttons(n).Value = true;
                end
            end
            for n = 1:numel(app.GradientcorrectionButtonGroup.Buttons)
                if strcmp(app.GradientcorrectionButtonGroup.Buttons(n).Text,parameters.RemoveGradient)
                    app.GradientcorrectionButtonGroup.Buttons(n).Value = true;
                end
            end
            app.SEMmodelDropDown.Value = parameters.SEM_model;

            app.BridgingthresholdEditField.Value = parameters.BridgingThreshold;
            app.PinchingthresholdEditField.Value = parameters.PinchingThreshold;

            for n = 1:numel(app.ImagetoneButtonGroup.Buttons)
                if strcmp(app.ImagetoneButtonGroup.Buttons(n).Text,parameters.LinesTone)
                    app.ImagetoneButtonGroup.Buttons(n).Value = true;
                end
            end
            for n = 1:numel(app.ContourdetectionButtonGroup.Buttons)
                if strcmp(app.ContourdetectionButtonGroup.Buttons(n).Text,parameters.ContoursEdge)
                    app.ContourdetectionButtonGroup.Buttons(n).Value = true;
                end
            end

            app.RadiusfractionEditField.Value = parameters.CntRadiusFraction;
            app.ContactsubimagesizeEditField.Value = parameters.CntSubimageSize;
            app.ManualrotationadjustmentdegSlider.Value = parameters.ManualRotation;
            app.DenoiseXEditField.Value = parameters.DenoiseX;
            app.DenoiseYEditField.Value = parameters.DenoiseY;
            app.APSmoothingEditField.Value = parameters.APSmoothing;
            app.LowfrequencyaverageEditField.Value = parameters.FN;
            app.HighfrequencyaverageEditField.Value = parameters.LN;
            app.LowfrequencyexclusionEditField.Value = parameters.ExN;
            app.HighfrequencyexclusionEditField.Value = parameters.ExNe;
            app.CorrelationfrequencyEditField.Value = parameters.CF;

            app.MultitaperButton.Value = parameters.MultiTaper;
            app.TaperfunctionsDropDown.Value = parameters.MultiTaperFunctionType;
            app.NumberoftapersEditField.Value = parameters.TaperNumber;
            %dataStructure.parameters = parameters;
            app.BrightedgeCheckBox.Value = parameters.BrightEdge;
            if parameters.BrightEdge
                app.BrightedgeselectionButtonGroup.Visible = "on";
                app.PolynomialButton.Enable = "off";
                app.ThresholdButton.Enable = "off";
                app.LinearButton_2.Enable = "off";
            else
                app.BrightedgeselectionButtonGroup.Visible = "off";
                app.PolynomialButton.Enable = "on";
                app.ThresholdButton.Enable = "on";
                app.LinearButton_2.Enable = "on";
            end
            app.BrightedgeselectionButtonGroup.Buttons(parameters.BrightEdgeSelection).Value = 1;
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            exportData(app)
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if value == 1
                app.StopButton.Visible = 'on';
                app.PauseButton.Text = 'Resume';
                waitfor(app.PauseButton,'Value',0)
            else
                app.StopButton.Visible = 'off';
                app.PauseButton.Text = 'Pause';
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.UserData = 'stop';
        end

        % Value changed function: ShowellipticalfitCheckBox
        function ShowellipticalfitCheckBoxValueChanged(app, event)
            displayData(app)
        end

        % Selection changed function: ContourMetricButtonGroup
        function ContourMetricButtonGroupSelectionChanged(app, event)
            displayData(app)
        end

        % Value changed function: ManualrotationadjustmentdegSlider
        function ManualrotationadjustmentdegSliderValueChanged(app, event)
            alpha = app.ManualrotationadjustmentdegSlider.Value;
            app.ManualrotationadjustmentdegSlider.UserData = alpha;
            axp = app.ImageParameters;
            id = app.AnalysisprogressGauge.UserData;
            dataStructure = app.ExportdataButton.UserData;
            rawImages = dataStructure.('rawImages');
            I = imrotate(rawImages{id},alpha,'nearest','crop');
            imagesc(axp,I);axis(axp,'image');colormap(axp,gray)
            rawImagesAdjusted = dataStructure.('rawImagesAdjusted');
            rawImagesAdjusted{id} = I;
            dataStructure.('rawImagesAdjusted') = rawImagesAdjusted;
            app.ExportdataButton.UserData = dataStructure;
            app.ManualrotationangleEditField.Value = alpha;
        end

        % Cell selection callback: dataTable, dataTable_Contacts
        function dataTableCellSelection2(app, event)
            indices = event.Indices;
            dataStructure = app.ExportdataButton.UserData;
            idl = dataStructure.idl;
            idc = dataStructure.idc;
            if strcmp(event.Source.Tag,'LinesTable')
                if ~isempty(indices)
                    id  = indices(1);
                    if id==sum(idl)+1
                        %Average
                        id = 0;
                    else
                        %id = find(cumsum(idl)==id,1);
                        Tab = app.dataTable.Data;
                        fname = Tab{id,2};
                        id = find(strcmp(dataStructure.fileName,fname),1);
                    end
                else
                    id  = [];
                end
            elseif strcmp(event.Source.Tag,'ContactsTable')
                if ~isempty(indices)
                    id  = indices(1);
                    Tab = app.dataTable_Contacts.Data;
                    fname = Tab{id,2};
                    id = find(strcmp(dataStructure.fileName,fname),1);
                else
                    id  = [];
                end
            end


            app.AnalysisprogressGauge.UserData = id;
            displayData(app)
        end

        % Button pushed function: ExportMatlabFIGButton
        function ExportMatlabFIGButtonPushed(app, event)
            fignew1 = figure('Visible','off'); % Invisible figure
            nax1 = copyobj(app.Image,fignew1);
            %set(nax1,'Position',get(groot,'DefaultAxesPosition'))
            fignew2 = figure('Visible','off'); % Invisible figure

            nax2 = copyobj(app.Metric,fignew2);
            %set(nax2,'Position',get(groot,'DefaultAxesPosition'))
            set(fignew1,'CreateFcn','set(gcbf,''Visible'',''on'')')
            set(fignew2,'CreateFcn','set(gcbf,''Visible'',''on'')')
            saveas(fignew1, 'test1', 'fig')
            saveas(fignew2, 'test2', 'fig')
            delete(fignew1)
            delete(fignew2)
        end

        % Close request function: SMILE23UIFigure
        function SMILE23UIFigureCloseRequest(app, event)

            if ispc
                s = '\';
                appdatafolder = [char(getenv('LocalAppData')) s 'SMILE'];
                if isfile([appdatafolder s 'SMILE2_msgLog.txt'])
                    diary off
                    delete([appdatafolder s 'SMILE2_msgLog.txt'])
                end
            else
                s = '/';
                if isfile([pwd s 'SMILE2_msgLog.txt'])
                    diary off
                    delete([pwd s 'SMILE2_msgLog.txt'])
                end
            end

            delete(app)
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            if ispc
                s = '\';
                appdatafolder = [char(getenv('LocalAppData')) s 'SMILE'];
                diary off
                if isfile([appdatafolder s 'SMILE2_msgLog.txt'])
                    delete([appdatafolder s 'SMILE2_msgLog.txt'])
                end
                app.MessagesTextArea.Value = '';
                diary([appdatafolder s 'SMILE2_msgLog.txt'])
                diary on
            else
                s = '/';
                diary off
                if isfile([pwd s 'SMILE2_msgLog.txt'])
                    delete([pwd s 'SMILE2_msgLog.txt'])
                end
                app.MessagesTextArea.Value = '';
                diary([pwd s 'SMILE2_msgLog.txt'])
                diary on
            end


        end

        % Button down function: MessagelogTab
        function MessagelogTabButtonDown(app, event)
            if ispc
                s = '\';
                appdatafolder = [char(getenv('LocalAppData')) s 'SMILE'];
                if isfile([appdatafolder s 'SMILE2_msgLog.txt'])
                    app.MessagesTextArea.Value = fileread([appdatafolder s 'SMILE2_msgLog.txt']);
                end
            else
                s = '/';
                if isfile([pwd s 'SMILE2_msgLog.txt'])
                    app.MessagesTextArea.Value = fileread([pwd s 'SMILE2_msgLog.txt']);
                end
            end

        end

        % Value changed function: MultitaperButton
        function MultitaperButtonValueChanged(app, event)
            value = app.MultitaperButton.Value;

        end

        % Key press function: dataTable, dataTable_Contacts
        function dataTableKeyPress(app, event)
            [Fields,Fields_C] = FieldNames(app);
            key = event.Key;
            if strcmpi(key,'t')
                a = app.dataTable.Selection;
                b = app.dataTable_Contacts.Selection;
                dataStructure = app.ExportdataButton.UserData;
                if strcmp(event.Source.Tag,'LinesTable')
                    if ~isempty(a)
                        a = a(:,1);
                        app.dataTable.Data(a,3) = {'Contacts'};
                        movingRows = cell(length(a),length(Fields_C));
                        movingRows(1:length(a),1:3)=app.dataTable.Data(a,1:3);
                        cntr = 0;
                        for n = 1:length(a)
                            cntr = cntr+1;
                            for cv = 1:size(dataStructure.fileName,1)
                                if strcmp(dataStructure.fileName{cv},app.dataTable.Data{a(n),2})
                                    dataStructure.idc(cv)=true;
                                    dataStructure.idl(cv)=false;
                                    dataStructure.Type(cv)={'Contacts'};
                                end
                            end

                        end

                        app.ExportdataButton.UserData = dataStructure;
                        app.dataTable_Contacts.Data = [app.dataTable_Contacts.Data;...
                            movingRows];
                        app.dataTable.Data(a,:)=[];
                    end
                else
                    if ~isempty(b)
                        b = b(:,1);
                        app.dataTable_Contacts.Data(b,3)={'Lines'};
                        movingRows = cell(length(b),length(Fields));
                        movingRows(1:length(b),1:3)=app.dataTable_Contacts.Data(b,1:3);
                        for n = 1:length(b)
                            for cv = 1:size(dataStructure.fileName,1)
                                if strcmp(dataStructure.fileName{cv},app.dataTable_Contacts.Data{b(n),2})
                                    dataStructure.idl(cv)=true;
                                    dataStructure.idc(cv)=false;
                                    dataStructure.Type(cv)={'Lines'};
                                end
                            end
                        end
                        app.ExportdataButton.UserData = dataStructure;
                        app.dataTable.Data = [app.dataTable.Data;...
                            movingRows];
                        app.dataTable_Contacts.Data(b,:)=[];
                    end
                end
            end

        end

        % Button pushed function: AutomaticfeaturerecognitionButton
        function AutomaticfeaturerecognitionButtonPushed(app, event)
            if strcmp(app.AutomaticfeaturerecognitionButton.Text,...
                    'Manual feature selection')
                app.AutomaticfeaturerecognitionButton.Text = 'Automatic feature recognition';
            else
                app.AutomaticfeaturerecognitionButton.Text = 'Manual feature selection';
            end
        end

        % Button pushed function: EdgerangeButton
        function EdgerangeButtonPushed(app, event)
            S = app.EdgerangeButton.Text;
            if strcmp(S,'Edge range')
                app.EdgerangeButton.Text = 'CD fraction';
                app.EdgedetectionrangeEditField.Enable = "on";
                app.CDfractionEditField.Enable = "off";
            else
                app.EdgerangeButton.Text = 'Edge range';
                app.EdgedetectionrangeEditField.Enable = "off";
                app.CDfractionEditField.Enable = "on";
            end
        end

        % Value changed function: ManualrotationangleEditField
        function ManualrotationangleEditFieldValueChanged2(app, event)
            value = app.ManualrotationangleEditField.Value;
            app.ManualrotationadjustmentdegSlider.Value = value;
            ManualrotationadjustmentdegSliderValueChanged(app,event)
        end

        % Value changed function: BrightedgeCheckBox
        function BrightedgeCheckBoxValueChanged(app, event)
            value = app.BrightedgeCheckBox.Value;
            if value
                app.BrightedgeselectionButtonGroup.Visible = "on";
                app.PolynomialButton.Enable = "off";
                app.ThresholdButton.Enable = "off";
                app.LinearButton_2.Enable = "off";
            else
                app.BrightedgeselectionButtonGroup.Visible = "off";
                app.PolynomialButton.Enable = "on";
                app.ThresholdButton.Enable = "on";
                app.LinearButton_2.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMILE23UIFigure = uifigure('Visible', 'off');
            app.SMILE23UIFigure.Name = 'SMILE 2.3';
            app.SMILE23UIFigure.CloseRequestFcn = createCallbackFcn(app, @SMILE23UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SMILE23UIFigure);
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.Metric = uiaxes(app.ImagesTab);
            title(app.Metric, 'Title')
            xlabel(app.Metric, 'X')
            ylabel(app.Metric, 'Y')
            zlabel(app.Metric, 'Z')
            app.Metric.Tag = 'axMetric';
            app.Colorbar = uiaxes(app.ImagesTab);
            title(app.Colorbar, 'Title')
            xlabel(app.Colorbar, 'X')
            ylabel(app.Colorbar, 'Y')
            zlabel(app.Colorbar, 'Z')
            app.Colorbar.Visible = 'off';
            app.Image = uiaxes(app.ImagesTab);
            title(app.Image, 'Title')
            xlabel(app.Image, 'X')
            ylabel(app.Image, 'Y')
            zlabel(app.Image, 'Z')
            app.Image.Tag = 'axImage1';
            app.ContourMetricButtonGroup = uibuttongroup(app.ImagesTab);
            app.ContourMetricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContourMetricButtonGroupSelectionChanged, true);
            app.ContourMetricButtonGroup.Title = 'Contour Metric';
            app.ContourMetricButtonGroup.Visible = 'off';
            app.RadiusButton = uiradiobutton(app.ContourMetricButtonGroup);
            app.RadiusButton.Text = 'Radius';
            app.EllipticityButton = uiradiobutton(app.ContourMetricButtonGroup);
            app.EllipticityButton.Text = 'Ellipticity';
            app.FitstderrorButton = uiradiobutton(app.ContourMetricButtonGroup);
            app.FitstderrorButton.Text = 'Fit std error';
            app.AngleButton = uiradiobutton(app.ContourMetricButtonGroup);
            app.AngleButton.Text = 'Angle';
            app.NoneButton_2 = uiradiobutton(app.ContourMetricButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.DataDisplayButtonGroup = uibuttongroup(app.ImagesTab);
            app.DataDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataDisplayButtonGroupSelectionChanged, true);
            app.DataDisplayButtonGroup.Title = 'Data Display';
            app.LCDUButton = uiradiobutton(app.DataDisplayButtonGroup);
            app.LCDUButton.Text = 'LCDU';
            app.PSDButton = uiradiobutton(app.DataDisplayButtonGroup);
            app.PSDButton.Text = 'PSD';
            app.PSDButton.Value = true;
            app.HDCFButton = uiradiobutton(app.DataDisplayButtonGroup);
            app.HDCFButton.Text = 'HDCF';
            app.dataTable = uitable(app.ImagesTab);
            app.dataTable.ColumnName = '';
            app.dataTable.RowName = {};
            app.dataTable.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection2, true);
            app.dataTable.Tag = 'LinesTable';
            app.dataTable.KeyPressFcn = createCallbackFcn(app, @dataTableKeyPress, true);
            app.ImagesFolderButton = uibutton(app.ImagesTab, 'push');
            app.ImagesFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesFolderButtonPushed, true);
            app.ImagesFolderButton.Text = 'Images Folder';
            app.ProcessimagesButton = uibutton(app.ImagesTab, 'push');
            app.ProcessimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessimagesButtonPushed, true);
            app.ProcessimagesButton.Tag = 'buttonMeasure';
            app.ProcessimagesButton.Text = 'Process images';
            app.UnselectallButton = uibutton(app.ImagesTab, 'push');
            app.UnselectallButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectallButtonPushed, true);
            app.UnselectallButton.Text = 'Unselect all';
            app.SelectallButton = uibutton(app.ImagesTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.ExportdataButton = uibutton(app.ImagesTab, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Tag = 'buttonExport';
            app.ExportdataButton.Text = 'Export data';
            app.ShowerrorsButton = uibutton(app.ImagesTab, 'state');
            app.ShowerrorsButton.ValueChangedFcn = createCallbackFcn(app, @ShowerrorsButtonValueChanged, true);
            app.ShowerrorsButton.Text = 'Show errors';
            app.AnalysisprogressGaugeLabel = uilabel(app.ImagesTab);
            app.AnalysisprogressGaugeLabel.Text = 'Analysis progress';
            app.AnalysisprogressGauge = uigauge(app.ImagesTab, 'linear');
            app.StopButton = uibutton(app.ImagesTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.MetricButtonGroup = uibuttongroup(app.ImagesTab);
            app.MetricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataDisplayButtonGroupSelectionChanged, true);
            app.MetricButtonGroup.Title = 'Metric';
            app.LWRButton = uiradiobutton(app.MetricButtonGroup);
            app.LWRButton.Text = 'LWR';
            app.LWRButton.Value = true;
            app.LERButton = uiradiobutton(app.MetricButtonGroup);
            app.LERButton.Text = 'LER';
            app.LERleadButton = uiradiobutton(app.MetricButtonGroup);
            app.LERleadButton.Text = 'LER lead';
            app.LERtrailButton = uiradiobutton(app.MetricButtonGroup);
            app.LERtrailButton.Text = 'LER trail';
            app.PauseButton = uibutton(app.ImagesTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.ShowellipticalfitCheckBox = uicheckbox(app.ImagesTab);
            app.ShowellipticalfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowellipticalfitCheckBoxValueChanged, true);
            app.ShowellipticalfitCheckBox.Visible = 'off';
            app.ShowellipticalfitCheckBox.Text = 'Show elliptical fit';
            app.dataTable_Contacts = uitable(app.ImagesTab);
            app.dataTable_Contacts.ColumnName = '';
            app.dataTable_Contacts.RowName = {};
            app.dataTable_Contacts.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection2, true);
            app.dataTable_Contacts.Tag = 'ContactsTable';
            app.dataTable_Contacts.KeyPressFcn = createCallbackFcn(app, @dataTableKeyPress, true);
            app.ExportMatlabFIGButton = uibutton(app.ImagesTab, 'push');
            app.ExportMatlabFIGButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatlabFIGButtonPushed, true);
            app.ExportMatlabFIGButton.Visible = 'off';
            app.ExportMatlabFIGButton.Text = 'Export Matlab FIG';
            app.averageCheckBox = uicheckbox(app.ImagesTab);
            app.averageCheckBox.Enable = 'off';
            app.averageCheckBox.Visible = 'off';
            app.averageCheckBox.Text = 'average';
            app.ImageLoadingProgress = uilabel(app.ImagesTab);
            app.ImageLoadingProgress.Text = '';
            app.AutomaticfeaturerecognitionButton = uibutton(app.ImagesTab, 'push');
            app.AutomaticfeaturerecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticfeaturerecognitionButtonPushed, true);
            app.AutomaticfeaturerecognitionButton.Text = 'Automatic feature recognition';
            app.ExportedgesCheckBox = uicheckbox(app.ImagesTab);
            app.ExportedgesCheckBox.Text = 'Export edges';
            app.ExportPSDCheckBox = uicheckbox(app.ImagesTab);
            app.ExportPSDCheckBox.Text = 'Export PSD';
            app.ExportPSDaverageCheckBox = uicheckbox(app.ImagesTab);
            app.ExportPSDaverageCheckBox.Text = 'Export PSD average';
            app.ExportPSDaverageCheckBox.Value = true;
            app.BatchmodeCheckBox = uicheckbox(app.ImagesTab);
            app.BatchmodeCheckBox.Text = 'Batch mode';
            app.ExportcontactdetailsCheckBox = uicheckbox(app.ImagesTab);
            app.ExportcontactdetailsCheckBox.Text = 'Export contact details';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.ImageParameters = uiaxes(app.ParametersTab);
            title(app.ImageParameters, 'Title')
            xlabel(app.ImageParameters, 'X')
            ylabel(app.ImageParameters, 'Y')
            zlabel(app.ImageParameters, 'Z')
            app.ImageParameters.Tag = 'Image';
            app.ImagepreprocessingPanel = uipanel(app.ParametersTab);
            app.ImagepreprocessingPanel.Title = 'Image pre processing';
            app.ManualrotationadjustmentdegSliderLabel = uilabel(app.ImagepreprocessingPanel);
            app.ManualrotationadjustmentdegSliderLabel.Text = 'Manual rotation adjustment [deg]';
            app.ManualrotationadjustmentdegSlider = uislider(app.ImagepreprocessingPanel);
            app.ManualrotationadjustmentdegSlider.Limits = [-90 90];
            app.ManualrotationadjustmentdegSlider.ValueChangedFcn = createCallbackFcn(app, @ManualrotationadjustmentdegSliderValueChanged, true);
            app.AutorotationalignmentCheckBox = uicheckbox(app.ImagepreprocessingPanel);
            app.AutorotationalignmentCheckBox.Text = 'Auto rotation alignment';
            app.AutorotationalignmentCheckBox.Value = true;
            app.RemovedistortionCheckBox = uicheckbox(app.ImagepreprocessingPanel);
            app.RemovedistortionCheckBox.Text = 'Remove distortion';
            app.RemovedistortionCheckBox.Value = true;
            app.GradientcorrectionButtonGroup = uibuttongroup(app.ImagepreprocessingPanel);
            app.GradientcorrectionButtonGroup.Title = 'Gradient correction';
            app.NoneButton = uitogglebutton(app.GradientcorrectionButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LinearButton = uitogglebutton(app.GradientcorrectionButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.QuadraticButton = uitogglebutton(app.GradientcorrectionButtonGroup);
            app.QuadraticButton.Text = 'Quadratic';
            app.CubicButton = uitogglebutton(app.GradientcorrectionButtonGroup);
            app.CubicButton.Text = 'Cubic';
            app.maxXpixEditField_2Label = uilabel(app.ImagepreprocessingPanel);
            app.maxXpixEditField_2Label.Text = 'min Y [pix]';
            app.minXpixEditFieldLabel = uilabel(app.ImagepreprocessingPanel);
            app.minXpixEditFieldLabel.Text = 'min X [pix]';
            app.minXpixEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.SetROIButton = uibutton(app.ImagepreprocessingPanel, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @setROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.APSmoothingEditFieldLabel = uilabel(app.ImagepreprocessingPanel);
            app.APSmoothingEditFieldLabel.Text = 'AP Smoothing';
            app.APSmoothingEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.APSmoothingEditField.Value = 11;
            app.maxXpixEditField_4Label = uilabel(app.ImagepreprocessingPanel);
            app.maxXpixEditField_4Label.Text = 'max X [pix]';
            app.minYpixEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.maxYpixEditFieldLabel = uilabel(app.ImagepreprocessingPanel);
            app.maxYpixEditFieldLabel.Text = 'max Y [pix]';
            app.maxYpixEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.maxXpixEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.ManualrotationangleEditFieldLabel = uilabel(app.ImagepreprocessingPanel);
            app.ManualrotationangleEditFieldLabel.Text = 'Manual rotation angle';
            app.ManualrotationangleEditField = uieditfield(app.ImagepreprocessingPanel, 'numeric');
            app.ManualrotationangleEditField.ValueChangedFcn = createCallbackFcn(app, @ManualrotationangleEditFieldValueChanged2, true);
            app.EdgedetectionPanel = uipanel(app.ParametersTab);
            app.EdgedetectionPanel.Title = 'Edge detection';
            app.ImagetoneButtonGroup = uibuttongroup(app.EdgedetectionPanel);
            app.ImagetoneButtonGroup.Title = 'Image tone';
            app.PositiveButton = uiradiobutton(app.ImagetoneButtonGroup);
            app.PositiveButton.Text = 'Positive';
            app.PositiveButton.Value = true;
            app.NegativeButton = uiradiobutton(app.ImagetoneButtonGroup);
            app.NegativeButton.Text = 'Negative';
            app.UndefinedButton = uiradiobutton(app.ImagetoneButtonGroup);
            app.UndefinedButton.Text = 'Undefined';
            app.BridgingthresholdEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.BridgingthresholdEditFieldLabel.Text = 'Bridging threshold';
            app.BridgingthresholdEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.BridgingthresholdEditField.Value = 5;
            app.PinchingthresholdEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.PinchingthresholdEditFieldLabel.Text = 'Pinching threshold';
            app.PinchingthresholdEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.PinchingthresholdEditField.Value = 5;
            app.EdgefitfunctionButtonGroup = uibuttongroup(app.EdgedetectionPanel);
            app.EdgefitfunctionButtonGroup.Title = 'Edge fit function';
            app.LinearButton_2 = uitogglebutton(app.EdgefitfunctionButtonGroup);
            app.LinearButton_2.Tag = 'linear';
            app.LinearButton_2.Text = 'Linear';
            app.LinearButton_2.Value = true;
            app.PolynomialButton = uitogglebutton(app.EdgefitfunctionButtonGroup);
            app.PolynomialButton.Tag = 'polynomial';
            app.PolynomialButton.Text = 'Polynomial';
            app.ThresholdButton = uitogglebutton(app.EdgefitfunctionButtonGroup);
            app.ThresholdButton.Tag = 'threshold';
            app.ThresholdButton.Text = 'Threshold';
            app.BrightedgeCheckBox = uicheckbox(app.EdgefitfunctionButtonGroup);
            app.BrightedgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrightedgeCheckBoxValueChanged, true);
            app.BrightedgeCheckBox.Text = 'Bright edge';
            app.ThresholdEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.ThresholdEditField.Value = 0.5;
            app.CDfractionEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.CDfractionEditFieldLabel.Text = 'CD fraction';
            app.CDfractionEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.CDfractionEditField.Value = 0.25;
            app.MaxspikeEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.MaxspikeEditFieldLabel.Text = 'Max spike';
            app.MaxspikeEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.MaxspikeEditField.Value = 10;
            app.MinpeakdistanceEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.MinpeakdistanceEditFieldLabel.Text = 'Min peak distance';
            app.MinpeakdistanceEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.MinpeakdistanceEditField.Value = 10;
            app.MinpeakprominenceEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.MinpeakprominenceEditFieldLabel.Text = 'Min peak prominence';
            app.MinpeakprominenceEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.MinpeakprominenceEditField.Value = 0.36;
            app.PixelsizenmEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size [nm]';
            app.PixelsizenmEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.PixelsizenmEditField.Value = 1;
            app.SEMmodelDropDownLabel = uilabel(app.EdgedetectionPanel);
            app.SEMmodelDropDownLabel.Text = 'SEM model';
            app.SEMmodelDropDown = uidropdown(app.EdgedetectionPanel);
            app.SEMmodelDropDown.Items = {'Generic', 'Hitachi', 'Zeiss'};
            app.SEMmodelDropDown.Value = 'Generic';
            app.ThresholdrangeEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.ThresholdrangeEditFieldLabel.Enable = 'off';
            app.ThresholdrangeEditFieldLabel.Visible = 'off';
            app.ThresholdrangeEditFieldLabel.Text = 'Threshold range';
            app.ThresholdrangeEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.ThresholdrangeEditField.Editable = 'off';
            app.ThresholdrangeEditField.Enable = 'off';
            app.ThresholdrangeEditField.Visible = 'off';
            app.ThresholdrangeEditField.Value = 0.5;
            app.EdgedetectionrangeEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.EdgedetectionrangeEditFieldLabel.Text = 'Edge detection range';
            app.EdgedetectionrangeEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.EdgedetectionrangeEditField.Enable = 'off';
            app.EdgedetectionrangeEditField.Value = 5;
            app.EdgerangeButton = uibutton(app.EdgedetectionPanel, 'push');
            app.EdgerangeButton.ButtonPushedFcn = createCallbackFcn(app, @EdgerangeButtonPushed, true);
            app.EdgerangeButton.Text = 'Edge range';
            app.MulticoreCheckBox = uicheckbox(app.EdgedetectionPanel);
            app.MulticoreCheckBox.Text = 'Multi core';
            app.BrightedgeselectionButtonGroup = uibuttongroup(app.EdgedetectionPanel);
            app.BrightedgeselectionButtonGroup.Title = 'Bright edge selection';
            app.BrightedgeselectionButtonGroup.Visible = 'off';
            app.CenterButton_2 = uiradiobutton(app.BrightedgeselectionButtonGroup);
            app.CenterButton_2.Text = 'Center';
            app.CenterButton_2.Value = true;
            app.InnerButton = uiradiobutton(app.BrightedgeselectionButtonGroup);
            app.InnerButton.Text = 'Inner';
            app.OuterButton = uiradiobutton(app.BrightedgeselectionButtonGroup);
            app.OuterButton.Text = 'Outer';
            app.RoughnessPowerSpectralDensityfitPanel = uipanel(app.ParametersTab);
            app.RoughnessPowerSpectralDensityfitPanel.Title = 'Roughness Power Spectral Density fit';
            app.PSDmodelDropDownLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.PSDmodelDropDownLabel.Text = 'PSD model';
            app.PSDmodelDropDown = uidropdown(app.RoughnessPowerSpectralDensityfitPanel);
            app.PSDmodelDropDown.Items = {'Palasantzas1', 'Palasantzas2', 'Gaussian', 'No white noise', 'Floating alpha'};
            app.PSDmodelDropDown.Value = 'Palasantzas2';
            app.MaximumnumberofiterationsEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.MaximumnumberofiterationsEditFieldLabel.Text = 'Maximum number of iterations';
            app.MaximumnumberofiterationsEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.MaximumnumberofiterationsEditField.Value = 10000;
            app.MaximumfunctionevaluationsEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.MaximumfunctionevaluationsEditFieldLabel.Text = 'Maximum function evaluations';
            app.MaximumfunctionevaluationsEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.MaximumfunctionevaluationsEditField.Value = 10000;
            app.CorrelationfrequencyEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.CorrelationfrequencyEditFieldLabel.Text = 'Correlation frequency';
            app.CorrelationfrequencyEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.CorrelationfrequencyEditField.Value = 0.02;
            app.AlphaEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.AlphaEditField.Value = 2;
            app.LowfrequencyaverageEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.LowfrequencyaverageEditFieldLabel.Text = 'Low frequency average';
            app.LowfrequencyaverageEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.LowfrequencyaverageEditField.Value = 5;
            app.HighfrequencyaverageEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.HighfrequencyaverageEditFieldLabel.Text = 'High frequency average';
            app.HighfrequencyaverageEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.HighfrequencyaverageEditField.Value = 50;
            app.LowfrequencyexclusionEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.LowfrequencyexclusionEditFieldLabel.Text = 'Low frequency exclusion';
            app.LowfrequencyexclusionEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.LowfrequencyexclusionEditField.Value = 1;
            app.HighfrequencyexclusionEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.HighfrequencyexclusionEditFieldLabel.Text = 'High frequency exclusion';
            app.HighfrequencyexclusionEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.HighfrequencyexclusionEditField.Value = 1;
            app.MultitaperButton = uibutton(app.RoughnessPowerSpectralDensityfitPanel, 'state');
            app.MultitaperButton.ValueChangedFcn = createCallbackFcn(app, @MultitaperButtonValueChanged, true);
            app.MultitaperButton.Text = 'Multitaper';
            app.TaperfunctionsDropDownLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.TaperfunctionsDropDownLabel.Text = 'Taper functions';
            app.TaperfunctionsDropDown = uidropdown(app.RoughnessPowerSpectralDensityfitPanel);
            app.TaperfunctionsDropDown.Items = {'Slepian', 'Sinusoidal'};
            app.TaperfunctionsDropDown.Value = 'Slepian';
            app.NumberoftapersEditFieldLabel = uilabel(app.RoughnessPowerSpectralDensityfitPanel);
            app.NumberoftapersEditFieldLabel.Text = 'Number of tapers';
            app.NumberoftapersEditField = uieditfield(app.RoughnessPowerSpectralDensityfitPanel, 'numeric');
            app.NumberoftapersEditField.Value = 4;
            app.LoadparametersButton = uibutton(app.ParametersTab, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.SaveparametersButton = uibutton(app.ParametersTab, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.ContactspecificparametersPanel = uipanel(app.ParametersTab);
            app.ContactspecificparametersPanel.Title = 'Contact-specific parameters';
            app.DenoiseXEditFieldLabel = uilabel(app.ContactspecificparametersPanel);
            app.DenoiseXEditFieldLabel.Text = 'Denoise X';
            app.DenoiseXEditField = uieditfield(app.ContactspecificparametersPanel, 'numeric');
            app.DenoiseXEditField.Value = 5;
            app.DenoiseYEditFieldLabel = uilabel(app.ContactspecificparametersPanel);
            app.DenoiseYEditFieldLabel.Text = 'Denoise Y';
            app.DenoiseYEditField = uieditfield(app.ContactspecificparametersPanel, 'numeric');
            app.DenoiseYEditField.Value = 5;
            app.ContourdetectionButtonGroup = uibuttongroup(app.ContactspecificparametersPanel);
            app.ContourdetectionButtonGroup.Title = 'Contour detection';
            app.FilteredimageButton = uitogglebutton(app.ContourdetectionButtonGroup);
            app.FilteredimageButton.Text = 'Filtered image';
            app.FilteredimageButton.Value = true;
            app.EdgefitfunctionButton = uitogglebutton(app.ContourdetectionButtonGroup);
            app.EdgefitfunctionButton.Text = 'Edge fit function';
            app.ContactsubimagesizeEditFieldLabel = uilabel(app.ContactspecificparametersPanel);
            app.ContactsubimagesizeEditFieldLabel.Text = 'Contact sub-image size';
            app.ContactsubimagesizeEditField = uieditfield(app.ContactspecificparametersPanel, 'numeric');
            app.ContactsubimagesizeEditField.Value = 1.5;
            app.RadiusfractionEditFieldLabel = uilabel(app.ContactspecificparametersPanel);
            app.RadiusfractionEditFieldLabel.Text = 'Radius fraction';
            app.RadiusfractionEditField = uieditfield(app.ContactspecificparametersPanel, 'numeric');
            app.RadiusfractionEditField.Value = 0.3;
            app.ExcludeedgesCheckBox = uicheckbox(app.ContactspecificparametersPanel);
            app.ExcludeedgesCheckBox.Text = 'Exclude edges';
            app.ExcludeedgesCheckBox.Value = true;
            app.MessagelogTab = uitab(app.TabGroup);
            app.MessagelogTab.Title = 'Message log';
            app.MessagelogTab.ButtonDownFcn = createCallbackFcn(app, @MessagelogTabButtonDown, true);
            app.MessagesTextAreaLabel = uilabel(app.MessagelogTab);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.MessagelogTab);
            app.ClearlogButton = uibutton(app.MessagelogTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'SMILE version 2.3.3'; 'Release date: 2024/11/06'; ''; 'Iacopo Mochi'; 'Email: iacopo.mochi@psi.ch'; ''; ''; 'License:'; ''; 'This is free and unencumbered software released into the public domain.'; ''; 'Anyone is free to copy, modify, publish, use, compile, sell, or'; 'distribute this software, either in source code form or as a compiled'; 'binary, for any purpose, commercial or non-commercial, and by any'; 'means.'; ''; 'In jurisdictions that recognize copyright laws, the author or authors'; 'of this software dedicate any and all copyright interest in the'; 'software to the public domain. We make this dedication for the benefit'; 'of the public at large and to the detriment of our heirs and'; 'successors. We intend this dedication to be an overt act of'; 'relinquishment in perpetuity of all present and future rights to this'; 'software under copyright law.'; ''; 'THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND,'; 'EXPRESS OR IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF'; 'MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT.'; 'IN NO EVENT SHALL THE AUTHORS BE LIABLE FOR ANY CLAIM, DAMAGES OR'; 'OTHER LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE,'; 'ARISING FROM, OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR'; 'OTHER DEALINGS IN THE SOFTWARE.'; ''; 'For more information, please refer to <http://unlicense.org>'; ''};
            % Show the figure after all components are created
            app.SMILE23UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SMILE_SB

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SMILE23UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SMILE23UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMILE23UIFigure)
        end
    end
end